classdef SNUCOCILARIPPLE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        PushTool2                       matlab.ui.container.toolbar.PushTool
        PushTool3                       matlab.ui.container.toolbar.PushTool
        Spinner                         matlab.ui.control.Spinner
        SpinnerLabel                    matlab.ui.control.Label
        StatusLabel                     matlab.ui.control.Label
        FilenameSuffixEditFieldLabel_3  matlab.ui.control.Label
        FilenameSuffixEditField         matlab.ui.control.EditField
        FilenameSuffixEditFieldLabel    matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        RunTrelliswithDefaultSettingsButton  matlab.ui.control.Button
        StartECODESpinnerLabel          matlab.ui.control.Label
        StartECODESpinner               matlab.ui.control.Spinner
        EndECODESpinnerLabel            matlab.ui.control.Label
        EndECODESpinner                 matlab.ui.control.Spinner
        Image7                          matlab.ui.control.Image
        V211026YSRLabel                 matlab.ui.control.Label
        StimTab                         matlab.ui.container.Tab
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        TypeappropriatevaluesSinglechonlyLabel  matlab.ui.control.Label
        WaveformLabel                   matlab.ui.control.Label
        Singlepulse1Block333usCheckBox  matlab.ui.control.CheckBox
        InstantstimgoButton             matlab.ui.control.Button
        CheckifstimulusisvalidButton    matlab.ui.control.Button
        PolaritybinaryTextArea          matlab.ui.control.TextArea
        PolaritybinaryTextAreaLabel     matlab.ui.control.Label
        DelaymsTextArea                 matlab.ui.control.TextArea
        DelaymsTextAreaLabel            matlab.ui.control.Label
        AnodAmplitudeuATextArea         matlab.ui.control.TextArea
        AnodAmplitudeuATextAreaLabel    matlab.ui.control.Label
        CathAmplitudeuATextArea         matlab.ui.control.TextArea
        CathAmplitudeuATextAreaLabel    matlab.ui.control.Label
        AnodDurationmsTextArea          matlab.ui.control.TextArea
        AnodDurationmsTextAreaLabel     matlab.ui.control.Label
        CathDurationmsTextAreaLabel     matlab.ui.control.Label
        CathDurationmsTextArea          matlab.ui.control.TextArea
        StimcurrentlystandbyLabel       matlab.ui.control.Label
        NoerrorandplottingisstandbyLabel  matlab.ui.control.Label
        FrequencyHzTextArea             matlab.ui.control.TextArea
        FrequencyHzTextAreaLabel        matlab.ui.control.Label
        TrainLengthmsTextArea           matlab.ui.control.TextArea
        TrainLengthmsTextAreaLabel      matlab.ui.control.Label
        StimElectrodesTextAreaLabel     matlab.ui.control.Label
        StimElectrodesTextArea          matlab.ui.control.TextArea
        Image                           matlab.ui.control.Image
        StimstandbyButton               matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        RasterTab                       matlab.ui.container.Tab
        RefreshLabel                    matlab.ui.control.Label
        Image8                          matlab.ui.control.Image
        PlotDimrcSpinnerLabel           matlab.ui.control.Label
        Image_kernel                    matlab.ui.control.Image
        Image_neural                    matlab.ui.control.Image
        Image_behavioral                matlab.ui.control.Image
        Image_filesave                  matlab.ui.control.Image
        Image_stop                      matlab.ui.control.Image
        Image_fileopen                  matlab.ui.control.Image
        Image_play                      matlab.ui.control.Image
        DataLabel                       matlab.ui.control.Label
        ConfigurationLabel              matlab.ui.control.Label
        PlotOptionsLabel                matlab.ui.control.Label
        Image_fileopen_3                matlab.ui.control.Image
        PlotDimrcSpinner                matlab.ui.control.Spinner
        PlotDimrcSpinner2               matlab.ui.control.Spinner
        PlotRasterButton                matlab.ui.control.Button
        Image_fileopen_2                matlab.ui.control.Image
        ChSpinner                       matlab.ui.control.Spinner
        Lock                            matlab.ui.control.Image
        ChSpinnerLabel                  matlab.ui.control.Label
        DAQStatusLabel                  matlab.ui.control.Label
        NoeventdetectedLabel            matlab.ui.control.Label
        AntiTab                         matlab.ui.container.Tab
        Image10                         matlab.ui.control.Image
        Image9                          matlab.ui.control.Image
        ReferenceSlider                 matlab.ui.control.Slider
        ReferenceSliderLabel            matlab.ui.control.Label
        SliderforStimDelayLabel         matlab.ui.control.Label
        DurationSpinner                 matlab.ui.control.Spinner
        DurationSpinnerLabel            matlab.ui.control.Label
        AmplitudeSpinner                matlab.ui.control.Spinner
        AmplitudeSpinnerLabel           matlab.ui.control.Label
        PlayoptionLabel                 matlab.ui.control.Label
        Image_stop_2                    matlab.ui.control.Image
        Image_play_2                    matlab.ui.control.Image
        DataLabel_2                     matlab.ui.control.Label
        RefreshLabel_2                  matlab.ui.control.Label
        Image8_2                        matlab.ui.control.Image
        Image_fileopen_5                matlab.ui.control.Image
        Image_fileopen_4                matlab.ui.control.Image
        StimdelaySpinner                matlab.ui.control.Spinner
        StimdelaySpinnerLabel           matlab.ui.control.Label
        RefractorydurSpinner            matlab.ui.control.Spinner
        RefractorydurSpinnerLabel       matlab.ui.control.Label
        StimChSpinner                   matlab.ui.control.Spinner
        StimChSpinnerLabel              matlab.ui.control.Label
        RecChSpinner                    matlab.ui.control.Spinner
        RecChSpinnerLabel               matlab.ui.control.Label
        UnitDropDown                    matlab.ui.control.DropDown
        UnitDropDownLabel               matlab.ui.control.Label
        SliderforStimDelay              matlab.ui.control.Slider
        UIAxes_2                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        DialogApp % Description
        Axis_dialogApp_x
        Axis_dialogApp_y
        Save_option
        y_axis_dialogue
        x_axis_dialogue
    end

    properties (Access = public)
        plot_num;
        save;
        gauss_value;
        plot_num_axis;
        file_name;
        def_dir;
        Rasters;
        Raster_Source;
        Raster_index;
        Raster_template;
        Tab_template;
        Raster_Source_tmp;
        stimString;
        input_data;
        Tabgroup_normalized_pos;
        panel;
        xlim_normalized_pos;
        ylim_normalized_pos;
        button_normalized_pos;
        event_label_normalized_pos;
        ch_num_normalized_pos;
        plot_button_normalized_pos;
        row_normalized_pos;
        col_normalized_pos;
        lock_normalized_pos;
        play_normalized_pos;
        file_open_config_normalized_pos;
        file_save_config_normalized_pos;
        file_save_data_normalized_pos;
        file_open_data_normalized_pos;
        kernel_normalized_pos;
        neural_normalized_pos;
        behavioral_normalized_pos;
        stop_normalized_pos;
        data_normalized_pos
        plotdim_normalized_pos
        config_normalized_pos
        plotopt_normalized_pos
        ch_label_normalized_pos
        DAQ_normalized_pos
        StatusLabel_normalized_pos
        FilenameSuffixEditField_normalized_pos
        FilenameSuffixEditFieldLabel_normalized_pos
        FilenameSuffixEditFieldLabel_3_normalized_pos
        Spinner_normalized_pos
        SpinnerLabel_normalized_pos
        refreshtext_normalized_pos
        refresh_normalized_pos
        app_initialized
        select_all_x
        select_all_y
        new_Raster;
        outer_timer_running;
        update_plottingtools
        anti_ch;
        unit;
        Anti_running;
        anti_stim_ready;
    first_time_anti;
    anti_stim_time;
    delayline;
    referenceline;
    anti_domain;
    anti_range;
    Anti_end;
    Anti_refresh;
    past_dialogue_ind
    y_past_dialogue_ind
    x_past_dialogue_ind
    Image2_normalized_pos
    StartECODESpinner_normalized_pos
    RunTrelliswithDefaultSettingsButton_normalized_pos
    V211026YSRLabel_normalized_pos
    Image7_normalized_pos
    EndECODESpinner_normalized_pos
    StartECODESpinnerLabel_normalized_pos
    EndECODESpinnerLabel_normalized_pos
    TypeappropriatevaluesSinglechonlyLabel_normalized_pos
    Singlepulse1Block333usCheckBox_normalized_pos
    StimElectrodesTextArea_normalized_pos
    TrainLengthmsTextArea_normalized_pos
    FrequencyHzTextArea_normalized_pos
    CathDurationmsTextArea_normalized_pos
    AnodDurationmsTextArea_normalized_pos
    CathAmplitudeuATextArea_normalized_pos
    AnodAmplitudeuATextArea_normalized_pos
    DelaymsTextArea_normalized_pos
    PolaritybinaryTextArea_normalized_pos
    CheckifstimulusisvalidButton_normalized_pos
    WaveformLabel_normalized_pos
    NoerrorandplottingisstandbyLabel_normalized_pos
    UIAxes_normalized_pos
    StimcurrentlystandbyLabel_normalized_pos
    Image_normalized_pos
    StimstandbyButton_normalized_pos
    InstantstimgoButton_normalized_pos
    Label_3_normalized_pos
    Label_2_normalized_pos
    Label_normalized_pos
    RecChSpinner_normalized_pos
    StimdelaySpinner_normalized_pos
    AmplitudeSpinner_normalized_pos
    StimChSpinner_normalized_pos
    RefractorydurSpinner_normalized_pos
    DurationSpinner_normalized_pos
    UnitDropDown_normalized_pos
    PlayoptionLabel_normalized_pos
    DataLabel_2_normalized_pos
    RefreshLabel_2_normalized_pos
    Image_play_2_normalized_pos
    Image_stop_2_normalized_pos
    Image_fileopen_4_normalized_pos
    Image_fileopen_5_normalized_pos
    Image8_2_normalized_pos
    UIAxes_2_normalized_pos
    SliderforStimDelay_normalized_pos
    ReferenceSlider_normalized_pos
    end


    methods (Access = private)
    end

    methods (Access = public)
        function  updateplot(app,title,ref,mark,must,mustnot,one,plot_num)
            try
                ref_num = input2numvec(ref);
                mark_num = input2numvec(mark);
                must_num = input2numvec(must);
                mustnot_num = input2numvec(mustnot);
                one_num = input2numvec(one);
                title_format = sprintf("R: %d, M: %d",ref_num,mark_num);

                if length(ref_num)>1  || length(mark_num)>1
                    app.StatusLabel.Text = "Error: Check the Ecode format!";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [1 0 0];
                else

                    app.Rasters{app.DialogApp.Raster_Sourcenum}.UserData.Ref_Ecode_str{plot_num} = ref;
                    app.Rasters{app.DialogApp.Raster_Sourcenum}.UserData.Mark_Ecode_str{plot_num} = mark;
                    app.Rasters{app.DialogApp.Raster_Sourcenum}.UserData.Must_Ecode_str{plot_num} = must;
                    app.Rasters{app.DialogApp.Raster_Sourcenum}.UserData.MustNot_Ecode_str{plot_num} = mustnot;
                    app.Rasters{app.DialogApp.Raster_Sourcenum}.UserData.One_Ecode_str{plot_num} = one;
                    if ~isempty(title)
                        app.Rasters{app.DialogApp.Raster_Sourcenum}.UserData.Title{plot_num} = title;
                    else
                        app.Rasters{app.DialogApp.Raster_Sourcenum}.UserData.Title{plot_num} = title_format;
                    end
                    app.Rasters{app.DialogApp.Raster_Sourcenum}.UserData.Ref_Ecode{plot_num} = ref_num;
                    app.Rasters{app.DialogApp.Raster_Sourcenum}.UserData.Mark_Ecode{plot_num} = mark_num;
                    app.Rasters{app.DialogApp.Raster_Sourcenum}.UserData.Must_Ecode{plot_num} = must_num;
                    app.Rasters{app.DialogApp.Raster_Sourcenum}.UserData.MustNot_Ecode{plot_num} = mustnot_num;
                    app.Rasters{app.DialogApp.Raster_Sourcenum}.UserData.One_Ecode{plot_num} = one_num;
                    app.StatusLabel.Text = "Status";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [0 1 0];


                end

            catch
                app.StatusLabel.Text = "Error: Check the Ecode format!";
                app.StatusLabel.HorizontalAlignment ='right';
                app.StatusLabel.FontColor = [1 0 0];

            end

        end

        function  updateplot_axis_x(app,x_lower_lim,x_upper_lim,plot_num_axis,all)
            app.Rasters{app.Axis_dialogApp_x.Raster_Sourcenum}.UserData.axis_data{plot_num_axis}.x_lower_lim = x_lower_lim;
            app.Rasters{app.Axis_dialogApp_x.Raster_Sourcenum}.UserData.axis_data{plot_num_axis}.x_upper_lim = x_upper_lim;
            if all
                for i = 1:length(app.Rasters{app.Axis_dialogApp_x.Raster_Sourcenum}.UserData.axis_data)
                app.Rasters{app.Axis_dialogApp_x.Raster_Sourcenum}.UserData.axis_data{i}.x_lower_lim = x_lower_lim;
                app.Rasters{app.Axis_dialogApp_x.Raster_Sourcenum}.UserData.axis_data{i}.x_upper_lim = x_upper_lim;
                end
            end
        end


        function  updateplot_axis_y(app,yl_lower_lim,yl_upper_lim,plot_num_axis,all)

            app.Rasters{app.Axis_dialogApp_y.Raster_Sourcenum}.UserData.axis_data{plot_num_axis}.yl_lower_lim = yl_lower_lim;
            app.Rasters{app.Axis_dialogApp_y.Raster_Sourcenum}.UserData.axis_data{plot_num_axis}.yl_upper_lim = yl_upper_lim;
            if all
             for i = 1:length( app.Rasters{app.Axis_dialogApp_y.Raster_Sourcenum}.UserData.axis_data)
            app.Rasters{app.Axis_dialogApp_y.Raster_Sourcenum}.UserData.axis_data{i}.yl_lower_lim = yl_lower_lim;
            app.Rasters{app.Axis_dialogApp_y.Raster_Sourcenum}.UserData.axis_data{i}.yl_upper_lim = yl_upper_lim;
             end
            end
        end

        function tmp = save_config(~,input_data)

            tmp = struct;
            tmp.Lock.UserData = input_data.Lock.UserData;
            tmp.ch= input_data.ch;
            tmp.row = input_data.row;
            tmp.col = input_data.col;
            tmp.Title = input_data.Title;
            tmp.Ref_Ecode = input_data.Ref_Ecode;
            tmp.Mark_Ecode = input_data.Mark_Ecode;
            tmp.Must_Ecode = input_data.Must_Ecode;
            tmp.MustNot_Ecode = input_data.MustNot_Ecode;
            tmp.One_Ecode = input_data.One_Ecode;
            tmp.Ref_Ecode_str = input_data.Ref_Ecode_str;
            tmp.Mark_Ecode_str = input_data.Mark_Ecode_str;
            tmp.Must_Ecode_str = input_data.Must_Ecode_str;
            tmp.MustNot_Ecode_str = input_data.MustNot_Ecode_str;
            tmp.One_Ecode_str = input_data.One_Ecode_str;
            tmp.axis_data = input_data.axis_data;
            tmp.x_lock = input_data.x_lock;

        end


        function load_config(app,source_num,config_data)

           % offset = length(app.Rasters{source_num}.Children)-app.Raster_index.reference;
           % RI = cell2struct(num2cell([cellfun(@(x) x+offset, struct2cell(app.Raster_index))]),...
             %   {'event_label','ch_num','plot_button','row','col','lock','play','file_open_config',...
             %   'file_save_config','file_save_data','file_open_data','kernel','neural','behavioral','stop','reference'});

       offset = length(app.Rasters{source_num}.Children)-app.Raster_index.reference;      
       RI = cell2struct(num2cell([cellfun(@(x) x+offset, struct2cell(app.Raster_index))]),...
                         {'event_label','ch_num','plot_button','row','col','lock','play','file_open_config',...
                'file_save_config','file_save_data','file_open_data','kernel','neural','behavioral','stop','plotdim','data','config','plotopt','ch_label','DAQ','refresh_text','refresh','reference'}); %label for Children; offset accounted


            app.Rasters{source_num}.UserData.Lock.UserData = config_data.Lock.UserData;
            app.Rasters{source_num}.UserData.ch = config_data.ch;
            app.Rasters{source_num}.UserData.row = config_data.row;
            app.Rasters{source_num}.UserData.col = config_data.col;
            app.Rasters{source_num}.Children(RI.ch_num).Value = app.Rasters{source_num}.UserData.ch;
            app.Rasters{source_num}.Children(RI.row).Value = app.Rasters{source_num}.UserData.row;
            app.Rasters{source_num}.Children(RI.col).Value = app.Rasters{source_num}.UserData.col;

            app.Rasters{source_num}.UserData.Ref_Ecode = config_data.Ref_Ecode;
            app.Rasters{source_num}.UserData.Mark_Ecode = config_data.Mark_Ecode;
            app.Rasters{source_num}.UserData.Must_Ecode = config_data.Must_Ecode;
            app.Rasters{source_num}.UserData.MustNot_Ecode = config_data.MustNot_Ecode;
            app.Rasters{source_num}.UserData.One_Ecode = config_data.One_Ecode;
            app.Rasters{source_num}.UserData.Ref_Ecode_str = config_data.Ref_Ecode_str;
            app.Rasters{source_num}.UserData.Mark_Ecode_str = config_data.Mark_Ecode_str;
            app.Rasters{source_num}.UserData.Must_Ecode_str = config_data.Must_Ecode_str;
            app.Rasters{source_num}.UserData.MustNot_Ecode_str = config_data.MustNot_Ecode_str;
            app.Rasters{source_num}.UserData.One_Ecode_str = config_data.One_Ecode_str;
            app.Rasters{source_num}.UserData.axis_data = config_data.axis_data;
            app.Rasters{source_num}.UserData.x_lock = config_data.x_lock;
            app.Rasters{source_num}.UserData.Title = config_data.Title;

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cd(['C:\Users\USER\Desktop\Ripple_Apps']);            
            app.RunTrelliswithDefaultSettingsButton.UserData = 0;
            app.def_dir = pwd;
            app.Raster_Source = 1;
            app.SliderforStimDelay.MinorTicks = [];
            app.SliderforStimDelay.MajorTicks = [];
            app.ReferenceSlider.MinorTicks = [];
            app.ReferenceSlider.MajorTicks = [];
            app.anti_domain = 4000;
            app.Anti_end = 0;
            app.Anti_refresh = 0;

            xlim(app.UIAxes_2,[-app.anti_domain/2 app.anti_domain/2]);
            app.SliderforStimDelay.Limits = [-app.anti_domain/2 0];
            app.ReferenceSlider.Limits = [0 app.anti_domain/2];
            app.SliderforStimDelay.Value = 0;
            app.SliderforStimDelay.Value = -app.anti_domain/2;
            app.RasterTab.UserData.Sourcenum = app.Raster_Source;
            app.select_all_x = 0;
            xline(app.UIAxes_2,0,'--');
            app.UIAxes_2.PickableParts = 'none';

            app.Rasters{1} = app.RasterTab;
            app.Rasters{1}.UserData.row = 1;
            app.Rasters{1}.UserData.col = 1;
            app.Rasters{1}.UserData.load = 0;
            app.Rasters{1}.UserData.config_load = 0;

            app.Rasters{1}.UserData.ch = 0;
            app.Rasters{1}.UserData.load = 0;
            app.Rasters{1}.UserData.inst_save = 0;
            app.Rasters{1}.UserData.Image_behavioral.UserData = 1;
            app.Rasters{1}.UserData.Image_neural.UserData = 1;
            app.Rasters{1}.UserData.Image_kernel.UserData = 1;
            app.Rasters{1}.UserData.Image_play.UserData = 0;
            app.Rasters{1}.UserData.Image_stop.UserData = 1;
            app.Rasters{1}.UserData.PlotRasterButton.UserData.firstplot = 0;
            app.Rasters{1}.UserData.PlotRasterButton.UserData.newplot = 0;
          %  app.Rasters{1}.UserData.Trial = 0;
          %  app.Rasters{1}.UserData.Eventcode = {};
          %  app.Rasters{1}.UserData.Eventtime = {};
          %  app.Rasters{1}.UserData.Spiketime = {};
          app.Rasters{1}.UserData.Refresh = 0;
          app.update_plottingtools = 0;
          app.Rasters{1}.UserData.overwrite = 0;
          app.Rasters{1}.UserData.append = 0;
        

            app.Tab_template = uitabgroup(app.UIFigure);
            app.Tab_template.Visible = 'off';
            app.Raster_template = copyobj(app.RasterTab,app.Tab_template,'legacy');
            app.Raster_Source_tmp = 1;

            app.Raster_index.event_label = 23;
            app.Raster_index.ch_num = 19;
            app.Raster_index.plot_button = 17;
            app.Raster_index.row = 15;
            app.Raster_index.col = 16;
            app.Raster_index.lock = 20;
            app.Raster_index.play = 10;
            app.Raster_index.file_open_config = 9;
            app.Raster_index.file_save_config = 7;
            app.Raster_index.file_save_data = 18;
            app.Raster_index.file_open_data = 14;
            app.Raster_index.kernel = 4;
            app.Raster_index.neural = 5;
            app.Raster_index.behavioral = 6;
            app.Raster_index.stop = 8;
            app.Raster_index.plotdim = 3;
            app.Raster_index.data = 11;
            app.Raster_index.config = 12;
            app.Raster_index.plotopt = 13;
            app.Raster_index.ch_label = 21;
            app.Raster_index.DAQ = 22;
            app.Raster_index.refresh_text = 1;
            app.Raster_index.refresh = 2;
            app.Raster_index.reference = length(app.RasterTab.Children);
        

            app.Label.Visible = 'off';
            app.Label_2.Visible = 'off';
            app.Label_3.Visible = 'off';


            app.EndECODESpinner.Value = 9876;
            app.StartECODESpinner.Value = 1234;
            app.app_initialized = 1;
            app.new_Raster = 0;
            app.outer_timer_running = 0;
            app.unit = 0;
            app.Anti_running = 0;
            app.anti_stim_ready = 1;
            app.first_time_anti = 1;

            app.Tabgroup_normalized_pos = app.TabGroup.Position(3:4)./app.UIFigure.Position(3:4);
            app.StatusLabel_normalized_pos = app.StatusLabel.Position(1:2)./app.UIFigure.Position(3:4);
            app.FilenameSuffixEditField_normalized_pos = app.FilenameSuffixEditField.Position(1:2)./app.UIFigure.Position(3:4);
            app.FilenameSuffixEditFieldLabel_normalized_pos = app.FilenameSuffixEditFieldLabel.Position(1:2)./app.UIFigure.Position(3:4);
            app.FilenameSuffixEditFieldLabel_3_normalized_pos = app.FilenameSuffixEditFieldLabel_3.Position(1:2)./app.UIFigure.Position(3:4);
            app.Spinner_normalized_pos = app.Spinner.Position(1:2)./app.UIFigure.Position(3:4);
            app.SpinnerLabel_normalized_pos = app.SpinnerLabel.Position(1:2)./app.UIFigure.Position(3:4);
            app.event_label_normalized_pos = app.NoeventdetectedLabel.Position(1:2)./app.UIFigure.Position(3:4);
            app.ch_num_normalized_pos = app.ChSpinner.Position(1:2)./app.UIFigure.Position(3:4);
            app.plot_button_normalized_pos = app.PlotRasterButton.Position(1:2)./app.UIFigure.Position(3:4);
            app.row_normalized_pos = app.PlotDimrcSpinner.Position(1:2)./app.UIFigure.Position(3:4);
            app.col_normalized_pos = app.PlotDimrcSpinner2.Position(1:2)./app.UIFigure.Position(3:4);
            app.lock_normalized_pos = app.Lock.Position(1:2)./app.UIFigure.Position(3:4);
            app.play_normalized_pos = app.Image_play.Position(1:2)./app.UIFigure.Position(3:4);
            app.file_open_config_normalized_pos = app.Image_fileopen.Position(1:2)./app.UIFigure.Position(3:4);
            app.file_save_config_normalized_pos = app.Image_filesave.Position(1:2)./app.UIFigure.Position(3:4);
            app.file_save_data_normalized_pos = app.Image_fileopen_2.Position(1:2)./app.UIFigure.Position(3:4);
            app.file_open_data_normalized_pos = app.Image_fileopen_3.Position(1:2)./app.UIFigure.Position(3:4);
            app.kernel_normalized_pos =   app.Image_kernel.Position(1:2)./app.UIFigure.Position(3:4);
            app.neural_normalized_pos =  app.Image_neural.Position(1:2)./app.UIFigure.Position(3:4);
            app.behavioral_normalized_pos = app.Image_behavioral.Position(1:2) ./app.UIFigure.Position(3:4);
            app.stop_normalized_pos = app.Image_stop.Position(1:2)./app.UIFigure.Position(3:4);
            app.data_normalized_pos = app.DataLabel.Position(1:2)./app.UIFigure.Position(3:4);
            app.plotdim_normalized_pos = app.PlotDimrcSpinnerLabel.Position(1:2)./app.UIFigure.Position(3:4);
            app.config_normalized_pos =   app.ConfigurationLabel.Position(1:2)./app.UIFigure.Position(3:4);
            app.plotopt_normalized_pos =  app.PlotOptionsLabel.Position(1:2)./app.UIFigure.Position(3:4);
            app.ch_label_normalized_pos = app.ChSpinnerLabel.Position(1:2) ./app.UIFigure.Position(3:4);
            app.DAQ_normalized_pos = app.DAQStatusLabel.Position(1:2)./app.UIFigure.Position(3:4);
            app.refreshtext_normalized_pos = app.RefreshLabel.Position(1:2) ./app.UIFigure.Position(3:4);
            app.refresh_normalized_pos = app.Image8.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.Image2_normalized_pos = app.Image2.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.StartECODESpinner_normalized_pos = app.StartECODESpinner.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.RunTrelliswithDefaultSettingsButton_normalized_pos = app.RunTrelliswithDefaultSettingsButton.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.V211026YSRLabel_normalized_pos = app.V211026YSRLabel.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.Image7_normalized_pos = app.Image7.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.EndECODESpinner_normalized_pos = app.EndECODESpinner.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.StartECODESpinnerLabel_normalized_pos = app.StartECODESpinnerLabel.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.EndECODESpinnerLabel_normalized_pos = app.EndECODESpinnerLabel.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
           
            app.TypeappropriatevaluesSinglechonlyLabel_normalized_pos  = app.TypeappropriatevaluesSinglechonlyLabel.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.Singlepulse1Block333usCheckBox_normalized_pos= app.Singlepulse1Block333usCheckBox.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.StimElectrodesTextArea_normalized_pos= app.StimElectrodesTextArea.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.TrainLengthmsTextArea_normalized_pos= app.TrainLengthmsTextArea.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.FrequencyHzTextArea_normalized_pos= app.FrequencyHzTextArea.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.CathDurationmsTextArea_normalized_pos= app.CathDurationmsTextArea.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.AnodDurationmsTextArea_normalized_pos= app.AnodDurationmsTextArea.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.CathAmplitudeuATextArea_normalized_pos= app.CathAmplitudeuATextArea.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.AnodAmplitudeuATextArea_normalized_pos= app.AnodAmplitudeuATextArea.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.DelaymsTextArea_normalized_pos= app.DelaymsTextArea.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.PolaritybinaryTextArea_normalized_pos= app.PolaritybinaryTextArea.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.CheckifstimulusisvalidButton_normalized_pos= app.CheckifstimulusisvalidButton.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.WaveformLabel_normalized_pos= app.WaveformLabel.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.NoerrorandplottingisstandbyLabel_normalized_pos= app.NoerrorandplottingisstandbyLabel.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.UIAxes_normalized_pos= app.UIAxes.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.StimcurrentlystandbyLabel_normalized_pos= app.StimcurrentlystandbyLabel.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.Image_normalized_pos= app.Image.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.StimstandbyButton_normalized_pos= app.StimstandbyButton.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.InstantstimgoButton_normalized_pos= app.InstantstimgoButton.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.Label_3_normalized_pos= app.Label_3.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.Label_2_normalized_pos= app.Label_2.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.Label_normalized_pos= app.Label.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];

            app.RecChSpinner_normalized_pos= app.RecChSpinner.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
            app.StimdelaySpinner_normalized_pos= app.StimdelaySpinner.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
           app.AmplitudeSpinner_normalized_pos= app.AmplitudeSpinner.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
           app.StimChSpinner_normalized_pos= app.StimChSpinner.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
           app.RefractorydurSpinner_normalized_pos= app.RefractorydurSpinner.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
           app.DurationSpinner_normalized_pos= app.DurationSpinner.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
          app.UnitDropDown_normalized_pos= app.UnitDropDown.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
          app.PlayoptionLabel_normalized_pos= app.PlayoptionLabel.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
          app.DataLabel_2_normalized_pos= app.DataLabel_2.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
          app.RefreshLabel_2_normalized_pos= app.RefreshLabel_2.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
          app.Image_play_2_normalized_pos= app.Image_play_2.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
          app.Image_stop_2_normalized_pos= app.Image_stop_2.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
           app.Image_fileopen_4_normalized_pos= app.Image_fileopen_4.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
          app.Image_fileopen_5_normalized_pos= app.Image_fileopen_5.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
          app.Image8_2_normalized_pos= app.Image8_2.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
          app.UIAxes_2_normalized_pos= app.UIAxes_2.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
           app.SliderforStimDelay_normalized_pos= app.SliderforStimDelay.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];
           app.ReferenceSlider_normalized_pos= app.ReferenceSlider.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];

        end

        % Value changed function: StimElectrodesTextArea
        function StimElectrodesTextAreaValueChanged(app, event)
            input = app.StimElectrodesTextArea.Value;

            try
                val = input2numvec(input);

                if length(val)>1
                    app.StatusLabel.Text = "Error: Check the Stim Electrode";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [1 0 0];

                elseif  ~isempty(input) && isempty(val)
                    app.StatusLabel.Text = "Error: Check the Stim Electrode";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [1 0 0];

                else

                    app.StimElectrodesTextArea.UserData = val;
                    app.StatusLabel.Text = "Status";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [0 1 0];
                end
            catch
                app.StatusLabel.Text = "Error: Check the Stim Electrode";
                app.StatusLabel.HorizontalAlignment ='right';
                app.StatusLabel.FontColor = [1 0 0];
            end

        end

        % Value changed function: TrainLengthmsTextArea
        function TrainLengthmsTextAreaValueChanged(app, event)


            input = app.TrainLengthmsTextArea.Value;
            try
                val = input2numvec(input);

                if length(val)>1
                    app.StatusLabel.Text = "Error: Check the Stim Electrode";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [1 0 0];

                elseif  ~isempty(input) && isempty(val)
                    app.StatusLabel.Text = "Error: Check the Stim Electrode";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [1 0 0];

                else

                    app.TrainLengthmsTextArea.UserData = val;
                    app.UIAxes.XLim = [0,app.TrainLengthmsTextArea.UserData];
                    app.StatusLabel.Text = "Status";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [0 1 0];
                end
            catch
                app.StatusLabel.Text = "Error: Check the Stim Electrode";
                app.StatusLabel.HorizontalAlignment ='right';
                app.StatusLabel.FontColor = [1 0 0];
            end


        end

        % Value changed function: FrequencyHzTextArea
        function FrequencyHzTextAreaValueChanged(app, event)


            input = app.FrequencyHzTextArea.Value;
            try
                val = input2numvec(input);

                if length(val)>1
                    app.StatusLabel.Text = "Error: Check the Stim Electrode";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [1 0 0];

                elseif  ~isempty(input) && isempty(val)
                    app.StatusLabel.Text = "Error: Check the Stim Electrode";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [1 0 0];

                else

                    app.FrequencyHzTextArea.UserData = val;

                    app.StatusLabel.Text = "Status";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [0 1 0];
                end
            catch
                app.StatusLabel.Text = "Error: Check the Stim Electrode";
                app.StatusLabel.HorizontalAlignment ='right';
                app.StatusLabel.FontColor = [1 0 0];
            end


        end

        % Callback function
        function CatDurationmsTextAreaValueChanged(app, event)

            input = app.CatDurationmsTextAre.Value;
            try
                val = input2numvec(input);

                if length(val)>1
                    app.StatusLabel.Text = "Error: Check the Stim Electrode";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [1 0 0];

                elseif  ~isempty(input) && isempty(val)
                    app.StatusLabel.Text = "Error: Check the Stim Electrode";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [1 0 0];

                else

                    app.CatDurationmsTextAre.UserData = val;
                    app.StatusLabel.Text = "Status";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [0 1 0];
                end
            catch
                app.StatusLabel.Text = "Error: Check the Stim Electrode";
                app.StatusLabel.HorizontalAlignment ='right';
                app.StatusLabel.FontColor = [1 0 0];
            end
        end

        % Value changed function: AnodDurationmsTextArea
        function AnodDurationmsTextAreaValueChanged(app, event)

            input = app.AnodDurationmsTextArea.Value;
            try
                val = input2numvec(input);

                if length(val)>1
                    app.StatusLabel.Text = "Error: Check the Stim Electrode";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [1 0 0];

                elseif  ~isempty(input) && isempty(val)
                    app.StatusLabel.Text = "Error: Check the Stim Electrode";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [1 0 0];

                else

                    app.AnodDurationmsTextArea.UserData = val;
                    app.StatusLabel.Text = "Status";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [0 1 0];
                    if app.Singlepulse1Block333usCheckBox.Value == 1
                     app.Label_2.Text = [num2str(round(0.03333*val,3)) + " ms"];
                    app.Label_2.Visible = 'on';
                    end
                end
            catch
                app.StatusLabel.Text = "Error: Check the Stim Electrode";
                app.StatusLabel.HorizontalAlignment ='right';
                app.StatusLabel.FontColor = [1 0 0];
            end

        end

        % Value changed function: CathAmplitudeuATextArea
        function CathAmplitudeuATextAreaValueChanged(app, event)


            input = app.CathAmplitudeuATextArea.Value;
            try
                val = input2numvec(input);

                if length(val)>1
                    app.StatusLabel.Text = "Error: Check the Stim Electrode";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [1 0 0];

                elseif  ~isempty(input) && isempty(val)
                    app.StatusLabel.Text = "Error: Check the Stim Electrode";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [1 0 0];

                else

                    app.CathAmplitudeuATextArea.UserData = val;
                        app.UIAxes.YLim = [-app.CathAmplitudeuATextArea.UserData(1)*1.5 app.UIAxes.YLim(2)];

                    app.StatusLabel.Text = "Status";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [0 1 0];
                end
            catch
                app.StatusLabel.Text = "Error: Check the Stim Electrode";
                app.StatusLabel.HorizontalAlignment ='right';
                app.StatusLabel.FontColor = [1 0 0];
            end


        end

        % Value changed function: AnodAmplitudeuATextArea
        function AnodAmplitudeuATextAreaValueChanged(app, event)


            input = app.AnodAmplitudeuATextArea.Value;
            try
                val = input2numvec(input);

                if length(val)>1
                    app.StatusLabel.Text = "Error: Check the Stim Electrode";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [1 0 0];

                elseif  ~isempty(input) && isempty(val)
                    app.StatusLabel.Text = "Error: Check the Stim Electrode";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [1 0 0];

                else

                    app.AnodAmplitudeuATextArea.UserData = val;
                    app.UIAxes.YLim = [app.UIAxes.YLim(1) app.AnodAmplitudeuATextArea.UserData(1)*1.5];
                    app.StatusLabel.Text = "Status";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [0 1 0];
                end
            catch
                app.StatusLabel.Text = "Error: Check the Stim Electrode";
                app.StatusLabel.HorizontalAlignment ='right';
                app.StatusLabel.FontColor = [1 0 0];
            end


        end

        % Button pushed function: StimstandbyButton
        function StimstandbyButtonPushed(app, event)
            %{
            if app.Singlepulse1Block333usCheckBox.Value == 0
                stim_protocol(app.StimElectrodesTextArea.UserData,app.TrainLengthmsTextArea.UserData,app.FrequencyHzTextArea.UserData,app.CathDurationmsTextArea.UserData,app.AnodDurationmsTextArea.UserData,app.CathAmplitudeuATextArea.UserData,app.AnodAmplitudeuATextArea.UserData,app.PolaritybinaryTextArea.UserData);
            elseif app.Singlepulse1Block333usCheckBox.Value == 1
                stim_protocol2(app.StimElectrodesTextArea.UserData,app.CathDurationmsTextArea.UserData,app.AnodDurationmsTextArea.UserData,app.CathAmplitudeuATextArea.UserData,app.AnodAmplitudeuATextArea.UserData,app.PolaritybinaryTextArea.UserData);
            end
            %}
            if strcmp(app.StimstandbyButton.Text , 'Stim standby!')
                app.Image.ImageSource = "피카츄.png";
                app.StimstandbyButton.Text = 'Stim off!';
                app.StimcurrentlystandbyLabel.Text = "Stim currently standby";
                app.StimstandbyButton.UserData = 1;

            elseif strcmp(app.StimstandbyButton.Text , 'Stim off!')
                app.StimstandbyButton.Text = 'Stim standby!';
                app.Image.ImageSource = "피카츄_대기.png";
                app.StimcurrentlystandbyLabel.Text = "Stim currently off";
                app.StimstandbyButton.UserData = 0;

            end

        end

        % Button pushed function: RunTrelliswithDefaultSettingsButton
        function RunTrelliswithDefaultSettingsButtonPushed(app, event)
            if app.StartECODESpinner.Value == 0 && app.EndECODESpinner.Value == 0
                app.StatusLabel.Text = "Set Start and End Eventcode first!";
                app.StatusLabel.HorizontalAlignment ='right';
                app.StatusLabel.FontColor = [1 0 0];


            else
                app.StatusLabel.Text = "Status";
                app.StatusLabel.HorizontalAlignment ='right';
                app.StatusLabel.FontColor = [0.39,0.83,0.07];

                app.RunTrelliswithDefaultSettingsButton.UserData = 1;
                if ~app.outer_timer_running
                    background_worker_start(app,app.StartECODESpinner.Value,app.EndECODESpinner.Value);
                    app.outer_timer_running = 1;
                end
                try
                    xippmex('trial')
                catch exception
                    currentFolder = pwd;
                    cd 'C:\Program Files (x86)\Ripple\Trellis\Apps\Trellis\bin'
                    system("C:\Program Files (x86)\Ripple\Trellis\Apps\Trellis\bin\start_Trellis.bat");
                    cd(currentFolder);
                end
            end


            %Trellis_default_setting();
        end

        % Button pushed function: CheckifstimulusisvalidButton
        function CheckifstimulusisvalidButtonPushed(app, event)
            %% error handling
            flag = 0;

            app.NoerrorandplottingisstandbyLabel.Text = "No error and plotting is standby...";
            plot(app.UIAxes,0,0,'.k');

            stim_elec = app.StimElectrodesTextArea.UserData;
            cath_dur = app.CathDurationmsTextArea.UserData;
            train_length = app.TrainLengthmsTextArea.UserData;
            frequency = app.FrequencyHzTextArea.UserData;
            anod_dur = app.AnodDurationmsTextArea.UserData;
            delay = app.DelaymsTextArea.UserData;
            anod_amp = app.AnodAmplitudeuATextArea.UserData;
            cath_amp = app.CathAmplitudeuATextArea.UserData;
            polarity = app.PolaritybinaryTextArea.UserData;
            period = 1000./frequency;
            phase_length = cath_dur+anod_dur+delay;
            if app.Singlepulse1Block333usCheckBox.Value == 0
                anod_dur_us =  anod_dur *1000;
                cath_dur_us = cath_dur * 1000;
                delay_us = delay * 1000;
            else
                anod_dur_us = round(anod_dur * 33.3);
                cath_dur_us = round(cath_dur * 33.3);
                delay_us = round(delay * 33.3);
            end
            
            %train_length_us =


            if app.Singlepulse1Block333usCheckBox.Value == 0
                len_vect = [length(stim_elec) length(cath_dur) length(train_length) length(frequency) length(anod_dur) length(anod_amp) length(cath_amp) length(polarity) length(delay) length(period)];

            elseif app.Singlepulse1Block333usCheckBox.Value == 1
                len_vect = [length(stim_elec) length(cath_dur) length(anod_dur) length(anod_amp) length(cath_amp) length(polarity) length(delay)];
                if ~(floor(cath_dur)==ceil(cath_dur)) || ~(floor(anod_dur)==ceil(anod_dur)) || ~(floor(delay)==ceil(delay))
                    flag = 1;
                    app.NoerrorandplottingisstandbyLabel.Text = "Error: Durations must be integer for single pulse";
                end
            end

            if any(train_length > 3600000)
                app.NoerrorandplottingisstandbyLabel.Text = "Error: Train duration should not exceed 3,600,000 ms.";
                flag = 1;
            elseif any(polarity ~= 1 & polarity ~= 0)
                app.NoerrorandplottingisstandbyLabel.Text = "Error: Polarity should be either 1 or 0.";
                flag = 1;

            elseif any(phase_length > period)
                app.NoerrorandplottingisstandbyLabel.Text = "Error: Phase length should be smaller than the period";
                flag = 1;
            elseif any(mod(train_length,frequency)~=0) && mod(train_length,2)==0
                app.NoerrorandplottingisstandbyLabel.Text = "Error: Pulse train length is not even multiple of frequency";
                flag = 1;
            elseif range(len_vect)~= 0
                app.NoerrorandplottingisstandbyLabel.Text = "Error: Entries have inconsistent # of values";
                flag = 1;

            elseif any(len_vect==0)
                app.NoerrorandplottingisstandbyLabel.Text = "Error: There is an empty entry";
                flag = 1;
            end

            if flag==0
                app.NoerrorandplottingisstandbyLabel.Text = "Stimulation parameter (below) is saved!" ;

                %% plotting
                %  cath_dur = app.CathDurationmsTextArea.UserData(1);
                %  anod_dur = app.AnodDurationmsTextArea.UserData(1);
                %  anod_amp = app.AnodAmplitudeuATextArea.UserData(1);
                %  cath_amp = app.CathAmplitudeuATextArea.UserData(1);
                %  polarity = app.PolaritybinaryTextArea.UserData(1);
                %  delay = app.DelaymsTextArea.UserData(1);

                pulse_length_us = anod_dur_us + cath_dur_us + delay_us;

                if app.Singlepulse1Block333usCheckBox.Value == 0
                    train_length = app.TrainLengthmsTextArea.UserData(1) * 1000;
                    frequency = app.FrequencyHzTextArea.UserData(1);
                elseif app.Singlepulse1Block333usCheckBox.Value == 1
                    train_length = pulse_length_us ;
                end

                plot(app.UIAxes,0:train_length,zeros(1,1+train_length),'r');
                hold(app.UIAxes,'on')

                freq_sec = 1000000;

                cath_amp_adj = ones(1,cath_dur_us) * -cath_amp;
                delay_amp_adj = zeros(1,delay_us);
                anod_amp_adj = ones(1,anod_dur_us) * anod_amp;

                if polarity == 1

                    y_axis = [anod_amp_adj delay_amp_adj cath_amp_adj];

                elseif polarity == 0

                    y_axis = [cath_amp_adj delay_amp_adj  anod_amp_adj];
                end

                % frequency_val
                y_value = zeros(1,train_length);
                if app.Singlepulse1Block333usCheckBox.Value == 0
                    freq_val = 0:(freq_sec/frequency):train_length;
                    for i=1:length(freq_val)-1
                        x_freq = (1:pulse_length_us) + freq_val(i);
                        y_value(x_freq(1):x_freq(end)) = y_axis;
                    end

                elseif app.Singlepulse1Block333usCheckBox.Value == 1
                    y_value(1:pulse_length_us) = y_axis;

                end
                final_x = [0 : train_length];

                final_y = [0 y_value];


                plot(app.UIAxes,final_x,final_y,'b');
                hold(app.UIAxes,'off');
                if app.Singlepulse1Block333usCheckBox.Value == 0
                    xticks(app.UIAxes,[0:freq_sec/frequency:train_length]);
                    xticklabels(app.UIAxes,strsplit(num2str([0:freq_sec/frequency:train_length]/1000),' '));
                    app.stimString = stim_param_to_string_modified(stim_elec,train_length/1000,frequency,cath_dur,anod_dur,...
                        anod_amp, cath_amp,delay,polarity,1);
                elseif app.Singlepulse1Block333usCheckBox.Value == 1
                    xticks(app.UIAxes,[0 train_length]);
                    xticklabels(app.UIAxes,strsplit(num2str([0 train_length]/1000),' '));
                    app.stimString = struct('elec', stim_elec, 'period', cath_dur + anod_dur + delay, 'repeats',1);
                    cath_tmp= struct('length',cath_dur,'ampl',cath_amp,'pol',0,'fs',0,'enable',1,'delay',0,'ampSelect',1);
                    anod_tmp= struct('length',anod_dur,'ampl',anod_amp,'pol',1,'fs',0,'enable',1,'delay',0,'ampSelect',1);
                    delay_tmp = struct('length',delay,'ampl', 0 ,'pol',1,'fs',0,'enable',1,'delay',0,'ampSelect',1);
                    if polarity ==0
                        
                       
                        app.stimString.seq(1) = cath_tmp;
                        app.stimString.seq(2) = delay_tmp;
                        app.stimString.seq(3) = anod_tmp;
                        
                    else
                        app.stimString.seq(1) = anod_tmp;
                        app.stimString.seq(2) = delay_tmp;
                        app.stimString.seq(3) = cath_tmp;
                        
                        
                    end
                    
                end
                xlim(app.UIAxes,[0 train_length]);


            end

        end

        % Value changed function: Singlepulse1Block333usCheckBox
        function Singlepulse1Block333usCheckBoxValueChanged(app, event)
            value = app.Singlepulse1Block333usCheckBox.Value;
            if value == 1
                app.TrainLengthmsTextArea.Visible =  'off';
                app.TrainLengthmsTextAreaLabel.Text = 'N/A';
                app.FrequencyHzTextArea.Visible =  'off';
                app.FrequencyHzTextAreaLabel.Text = 'N/A';
                app.AnodDurationmsTextAreaLabel.Text = 'Anod Dur. (block)';
                app.CathDurationmsTextAreaLabel.Text = 'Cath Dur. (block)';  
                app.DelaymsTextAreaLabel.Text = 'Delay (block)';
                   app.AnodDurationmsTextArea.Value = '';
                  app.CathDurationmsTextArea.Value = '';
                    app.DelaymsTextArea.Value = '';
                
            end
            if value == 0
                app.TrainLengthmsTextArea.Visible =  'on';
                app.TrainLengthmsTextAreaLabel.Text = 'Train Length (ms)';
                app.FrequencyHzTextArea.Visible =  'on';
                app.FrequencyHzTextAreaLabel.Text = 'Frequency (Hz)';
                app.AnodDurationmsTextAreaLabel.Text = 'Anod Duration (ms)';
                app.CathDurationmsTextAreaLabel.Text = 'Cath Duration (ms)';
                app.DelaymsTextAreaLabel.Text = 'Delay (ms)';
                app.Label.Visible = 'off';
                app.Label_2.Visible = 'off';
                app.Label_3.Visible = 'off';
                 app.AnodDurationmsTextArea.Value = '';
                  app.CathDurationmsTextArea.Value = '';
                    app.DelaymsTextArea.Value = '';

            end
        end

        % Value changed function: CathDurationmsTextArea
        function CathDurationmsTextAreaValueChanged(app, event)
            input = app.CathDurationmsTextArea.Value;
            try
                val = input2numvec(input);

                if length(val)>1
                    app.StatusLabel.Text = "Error: Check the Stim Electrode";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [1 0 0];

                elseif  ~isempty(input) && isempty(val)
                    app.StatusLabel.Text = "Error: Check the Stim Electrode";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [1 0 0];

                else

                    app.CathDurationmsTextArea.UserData = val;
                    app.StatusLabel.Text = "Status";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [0 1 0];
                    if app.Singlepulse1Block333usCheckBox.Value == 1
                    app.Label.Text = [num2str(round(0.03333*val,3)) + " ms"];
                    app.Label.Visible = 'on';
                    end
                end
            catch
                app.StatusLabel.Text = "Error: Check the Stim Electrode";
                app.StatusLabel.HorizontalAlignment ='right';
                app.StatusLabel.FontColor = [1 0 0];
            end
        end

        % Value changed function: PolaritybinaryTextArea
        function PolaritybinaryTextAreaValueChanged(app, event)
            input = app.PolaritybinaryTextArea.Value;
            try
                val = input2numvec(input);

                if length(val)>1
                    app.StatusLabel.Text = "Error: Check the Stim Electrode";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [1 0 0];

                elseif  ~isempty(input) && isempty(val)
                    app.StatusLabel.Text = "Error: Check the Stim Electrode";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [1 0 0];

                else

                    app.PolaritybinaryTextArea.UserData = val;
                    app.StatusLabel.Text = "Status";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [0 1 0];
                end
            catch
                app.StatusLabel.Text = "Error: Check the Stim Electrode";
                app.StatusLabel.HorizontalAlignment ='right';
                app.StatusLabel.FontColor = [1 0 0];
            end
        end

        % Value changed function: DelaymsTextArea
        function DelaymsTextAreaValueChanged(app, event)
            Delay = [];
            stim_tmp1 = app.DelaymsTextArea.Value;
            stim_tmp2 = split(stim_tmp1{1,1},' ');
            for i=1:length(stim_tmp2)
                Delay = [Delay str2double(stim_tmp2{i})];
            end
            Delay(ismissing(Delay)) = [];

            app.DelaymsTextArea.UserData = Delay;
            if app.Singlepulse1Block333usCheckBox.Value == 1
            app.Label_3.Text = [num2str(round(0.03333*Delay,3)) + " ms"];
                    app.Label_3.Visible = 'on';
            end
        end

        % Callback function
        function saveButtonPushed(app, event)

        end

        % Image clicked function: Image_fileopen
        function Image_fileopenClicked(app, event)
            %try
                
            event.Source.Parent.UserData.config_load = 1;
            cd([app.def_dir '\Raster_Data']);
            [file,path] = uigetfile;
            cd(path);
            load(file);
            load_config(app,event.Source.Parent.UserData.Sourcenum,app_tmp);
            PlotRasterButtonPushed(app,event);
            cd (app.def_dir);
            event.Source.Parent.UserData.config_load = 0;
                

            %{
catch
                app.StatusLabel.Text = "Error: Select appropriate file!";
                app.StatusLabel.HorizontalAlignment ='right';
                app.StatusLabel.FontColor = [1 0 0];
                cd(app.def_dir);
            end
            %}
        end

        % Image clicked function: Image_stop
        function Image_stopClicked(app, event)

            offset = length(app.Rasters{event.Source.Parent.UserData.Sourcenum}.Children)-app.Raster_index.reference;
            RI = cell2struct(num2cell([cellfun(@(x) x+offset, struct2cell(app.Raster_index))]),...
                         {'event_label','ch_num','plot_button','row','col','lock','play','file_open_config',...
                'file_save_config','file_save_data','file_open_data','kernel','neural','behavioral','stop','plotdim','data','config','plotopt','ch_label','DAQ','refresh_text','refresh','reference'}); %label for Children; offset accounted

            if strcmp(event.Source.ImageSource,'stop_select.PNG')==1
            elseif strcmp(event.Source.ImageSource,'stop_icon.PNG')==1
                event.Source.ImageSource =  'stop_select.PNG';
                event.Source.Parent.Children(RI.play).ImageSource = 'play_icon.PNG';
                event.Source.Parent.Children(RI.play).UserData = 0;
                event.Source.Parent.UserData.Image_play.UserData=0;


            end
        end

        % Image clicked function: Image_neural
        function Image_neuralClicked(app, event)


            if strcmp(event.Source.ImageSource,'neural_select.PNG')==1
                event.Source.ImageSource =  'neural_icon.PNG';
                event.Source.UserData = 0;
                event.Source.Parent.UserData.Image_neural.UserData = 0;

            elseif strcmp(event.Source.ImageSource,'neural_icon.PNG')==1
                event.Source.ImageSource =  'neural_select.PNG';
                event.Source.UserData = 1;
                event.Source.Parent.UserData.Image_neural.UserData = 1;

            end


        end

        % Image clicked function: Image_behavioral
        function Image_behavioralClicked(app, event)

            if strcmp( event.Source.ImageSource,'behavior_select.PNG')==1
                event.Source.ImageSource =  'behavior_icon.PNG';
                event.Source.UserData = 0;
                event.Source.Parent.UserData.Image_behavioral.UserData = 0;

            elseif strcmp(event.Source.ImageSource,'behavior_icon.PNG')==1
                event.Source.ImageSource =  'behavior_select.PNG';
                event.Source.UserData = 1;
                event.Source.Parent.UserData.Image_behavioral.UserData = 1;


            end

        end

        % Image clicked function: Image_kernel
        function Image_kernelClicked(app, event)

            if strcmp(event.Source.ImageSource,'kernel_select.PNG')==1
                event.Source.ImageSource =  'kernel_icon.PNG';
                event.Source.UserData = 0;
                event.Source.Parent.UserData.Image_kernel.UserData = 0;

            elseif strcmp(event.Source.ImageSource,'kernel_icon.PNG')==1
                event.Source.ImageSource =  'kernel_select.PNG';
                event.Source.UserData = 1;
                event.Source.Parent.UserData.Image_kernel.UserData = 1;


            end

        end

        % Image clicked function: Image_filesave
        function Image_filesaveClicked(app, event)

            if isempty(event.Source.Parent.UserData.PlotRasterButton.UserData)
                app.StatusLabel.Text = "Error : Click Plot Raster first!";
                app.StatusLabel.HorizontalAlignment ='right';
                app.StatusLabel.FontColor = [1 0 0];
            else
                try
                    app_tmp = save_config(app,event.Source.Parent.UserData);
                    cd ([app.def_dir '\Raster_Data']);
                    [name,dir]=uiputfile('app_tmp.mat','Saving configuration file...','Task_configuration_file');
                    cd (dir);
                    save(name,'app_tmp');
                    cd(app.def_dir);
                    app.Raster_Source = event.Source.Parent.UserData.Sourcenum;

                catch
                    app.StatusLabel.Text = "Error: Resetting the directory.";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [1 0 0];
                    cd(app.def_dir);
                end

            end

        end

        % Image clicked function: Image_play
        function Image_playClicked(app, event)

            offset = length(app.Rasters{event.Source.Parent.UserData.Sourcenum}.Children)-app.Raster_index.reference;
           RI = cell2struct(num2cell([cellfun(@(x) x+offset, struct2cell(app.Raster_index))]),...
                        {'event_label','ch_num','plot_button','row','col','lock','play','file_open_config',...
                'file_save_config','file_save_data','file_open_data','kernel','neural','behavioral','stop','plotdim','data','config','plotopt','ch_label','DAQ','refresh_text','refresh','reference'}); %label for Children; offset accounted

            if strcmp(event.Source.ImageSource,'play_select.PNG')==1


            elseif strcmp(event.Source.ImageSource,'play_icon.PNG')==1
                event.Source.ImageSource =  'play_select.PNG';
                event.Source.Parent.Children(RI.stop).ImageSource = 'stop_icon.PNG';
                event.Source.UserData = 1;
                event.Source.Parent.UserData.Image_play.UserData = 1;
                app.Raster_Source_tmp = event.Source.Parent.UserData.Sourcenum;
                event.Source.Parent.Children(RI.stop).UserData = 1;
                event.Source.Parent.UserData.Image_stop.UserData = 1;

                for i =1:length(app.Rasters)
                    if i~= event.Source.Parent.UserData.Sourcenum

                        offset = length(app.Rasters{event.Source.Parent.UserData.Sourcenum}.Children)-app.Raster_index.reference;
                        RI = cell2struct(num2cell([cellfun(@(x) x+offset, struct2cell(app.Raster_index))]),...
                              {'event_label','ch_num','plot_button','row','col','lock','play','file_open_config',...
                'file_save_config','file_save_data','file_open_data','kernel','neural','behavioral','stop','plotdim','data','config','plotopt','ch_label','DAQ','refresh_text','refresh','reference'}); %label for Children; offset accounted

                        app.Rasters{i}.UserData.Image_play.UserData = 0;
                        app.Rasters{i}.Children(RI.play).UserData = 0;
                        app.Rasters{i}.Children(RI.play).ImageSource = 'play_icon.PNG';

                        app.Rasters{i}.UserData.Image_stop.UserData = 1;
                        app.Rasters{i}.Children(RI.stop).UserData = 1;
                        app.Rasters{i}.Children(RI.stop).ImageSource = 'stop_select.PNG';


                    end
                end


            end
        end

        % Value changed function: PlotDimrcSpinner
        function PlotDimrcSpinnerValueChanged(app, event)

            app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.row = event.Value;

        end

        % Value changed function: PlotDimrcSpinner2
        function PlotDimrcSpinner2ValueChanged(app, event)
            app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.col = event.Value;

        end

        % Button pushed function: PlotRasterButton
        function PlotRasterButtonPushed(app, event)


            if app.RunTrelliswithDefaultSettingsButton.UserData == 0
                app.StatusLabel.Text = "Error: Run Trellis with default setting first!";
                app.StatusLabel.HorizontalAlignment ='right';
                app.StatusLabel.FontColor = [1 0 0];

            else
                app.StatusLabel.Text = "Plot has been created!";
                app.StatusLabel.HorizontalAlignment ='right';
                app.StatusLabel.FontColor = [0.39,0.83,0.07];

                if app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.PlotRasterButton.UserData.firstplot == 1 && (~app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.load && ~app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.config_load)
                    app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.PlotRasterButton.UserData.newplot = 1;
                end
                app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.PlotRasterButton.UserData.firstplot = 1;
                app.update_plottingtools = 1;


                offset = length(app.Rasters{event.Source.Parent.UserData.Sourcenum}.Children)-app.Raster_index.reference;
            RI = cell2struct(num2cell([cellfun(@(x) x+offset, struct2cell(app.Raster_index))]),...
                         {'event_label','ch_num','plot_button','row','col','lock','play','file_open_config',...
                'file_save_config','file_save_data','file_open_data','kernel','neural','behavioral','stop','plotdim','data','config','plotopt','ch_label','DAQ','refresh_text','refresh','reference'}); %label for Children; offset accounted

                stop_event.Source = app.Rasters{event.Source.Parent.UserData.Sourcenum}.Children(RI.stop);
                Image_stopClicked(app, stop_event);
                app.Raster_Source =  event.Source.Parent.UserData.Sourcenum;

                app.Rasters{event.Source.Parent.UserData.Sourcenum}.Children(RI.event_label).Text =  'Scanning for START ECODE';

                Button_tmp = app.Rasters{event.Source.Parent.UserData.Sourcenum}.Children(RI.plot_button);
                Kernel_tmp = app.Rasters{event.Source.Parent.UserData.Sourcenum}.Children(RI.kernel);
                Neural_tmp = app.Rasters{event.Source.Parent.UserData.Sourcenum}.Children(RI.neural);
                Behavioral_tmp = app.Rasters{event.Source.Parent.UserData.Sourcenum}.Children(RI.behavioral);
                delete(app.DialogApp);
                Button_tmp.UserData =1;

                if ~isempty(Button_tmp)
                    %    for i=1:length(Button_tmp)
                    Button_tmp.Enable = 'on';
                    %     end
                end


                sigma = 10;
                for gi = 1 : 201
                    app.gauss_value(gi) = 1/sqrt(2*pi)/sigma*exp(-1*((gi-101)^2/2/sigma^2));
                end

                Kernel_tmp.ImageSource =  'kernel_select.PNG';
                Kernel_tmp.UserData = 1;
                Behavioral_tmp.ImageSource =  'behavior_select.PNG';
                Behavioral_tmp.UserData = 1;
                Neural_tmp.ImageSource =  'neural_select.PNG';
                Neural_tmp.UserData = 1;
                app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.inst_save = 0;

                if ~app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.config_load && ~app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.load
                    app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.Title = cell(1,app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.row*app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.col);% Description
                    app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.Ref_Ecode = cell(1,app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.row*app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.col);% Description
                    app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.Mark_Ecode =  cell(1,app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.row*app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.col);% Description
                    app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.Must_Ecode = cell(1,app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.row*app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.col);% Description
                    app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.MustNot_Ecode = cell(1,app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.row*app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.col);% Description
                    app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.One_Ecode = cell(1,app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.row*app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.col);% Description
                    app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.axis_data = cell(1,app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.row*app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.col);%
                    app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.Ref_Ecode_str = cell(1,app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.row*app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.col);%% Description
                    app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.Mark_Ecode_str = cell(1,app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.row*app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.col);%% Description
                    app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.Must_Ecode_str = cell(1,app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.row*app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.col);%% Description
                    app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.MustNot_Ecode_str = cell(1,app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.row*app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.col);%% Description
                    app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.One_Ecode_str = cell(1,app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.row*app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.col);%v% Description
                    app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.x_lock = ones(1,app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.row*app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.col);
                    app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.Lock.UserData = 1;


                end


                app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.histo = cell(1,app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.row*app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.col);
                app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.event_plot = cell(1,app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.row*app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.col);
                app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.spike_plot = cell(1,app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.row*app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.col);
                app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.ref_plot = cell(1,app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.row*app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.col);

                obj = event.Source.Parent;
                app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.panel = uipanel(obj,'Position',[0 10 app.Rasters{event.Source.Parent.UserData.Sourcenum}.Position(3) app.Rasters{event.Source.Parent.UserData.Sourcenum}.Children(RI.file_save_data).Position(2)-20],'Title','','BackgroundColor',[0.87,0.89,0.95]);
                %p.HandleVisibility   = 'off';
                app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.panel.BorderType = 'none';
                app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.t1 = tiledlayout(           app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.panel, app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.row, app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.col);
                app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.t1.TileSpacing = 'compact';
                app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.t1.Padding = 'compact';
                app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.t1.Units = 'pixels';
                tile_dim = app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.t1.GridSize;
                app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.Panel_normalized_pos =           app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.panel.Position(3:4)./app.UIFigure.Position(3:4);


                for i = 1:tile_dim(1)*tile_dim(2)

                if ~app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.config_load && ~app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.load
                        app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.Title{i} ='';
                        app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.Ref_Ecode_str{i} = '';
                        app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.Mark_Ecode_str{i} = '';
                        app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.Must_Ecode_str{i} = '';
                        app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.MustNot_Ecode_str{i} = '';
                        app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.One_Ecode_str{i} ='';
                        app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.axis_data{i}.x_upper_lim = zeros(1,1);
                        app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.axis_data{i}.x_lower_lim = zeros(1,1);
                        app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.axis_data{i}.yl_upper_lim = zeros(1,1);
                        app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.axis_data{i}.yl_lower_lim = zeros(1,1);

                    end

                    tmp_t = nexttile(app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.t1,i) ;

                    if i >= tile_dim(1) * tile_dim(2) - tile_dim(2) + 1
                        xlabel(tmp_t,'time (ms)','FontSize' , 9 );
                    end

                    yyaxis(tmp_t,'left')
                    if tile_dim(1) ==1 && tile_dim(2) ==1
                           yyaxis(tmp_t,'left')

                            ylabel(tmp_t,'Spike rate (spk/s)','FontSize' , 9 );
                               yyaxis(tmp_t,'right')
                            ylabel(tmp_t,'Trial #','FontSize',9);
                    else

                        if mod(i,tile_dim(2))==1
                            yyaxis(tmp_t,'left')

                            ylabel(tmp_t,'Spike rate (spk/s)','FontSize' , 9 );

                        elseif mod (i,tile_dim(2))==0
                            yyaxis(tmp_t,'right')
                            ylabel(tmp_t,'Trial #','FontSize',9 );
                        end
                    end

                end
          
                for i = tile_dim(1)*tile_dim(2):-1:1
                    ind = [tile_dim(1) * tile_dim(2):-1:1];
                    app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.t1.Children(i).Units = 'pixels';
                    b = uiimage(           app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.panel,'Position',[(app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.t1.Children(i).Position(1)+app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.t1.Children(i).Position(3))-28, app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.t1.Children(i).Position(2)+app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.t1.Children(i).Position(4)-25, 20, 20]);
                    b.ImageSource = 'filter.PNG';
                    b.UserData = ind(i);
                    b.ImageClickedFcn = @ImageClicked;
                    app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.button{ind(i)} = b;
                    app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.button_normalized_pos{ind(i)} = app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.button{ind(i)}.Position(1:2)./app.UIFigure.Position(3:4);

                    xlim_image = uiimage(           app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.panel,'Position',[app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.t1.Children(i).InnerPosition(1), app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.t1.Children(i).InnerPosition(2)-10,app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.t1.Children(i).InnerPosition(3),20]);
                    xlim_image.ImageSource = 'Red_horz_line.PNG';
                    xlim_image.ScaleMethod = 'scaledown';
                    xlim_image.UserData = ind(i);
                    xlim_image.ImageClickedFcn = @ImageClicked_lim_x;
                    app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.xlim{ind(i)} = xlim_image;
                    %app.xlim{ind(i)}.Visible='off';
                    app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.xlim_normalized_pos{ind(i)} = app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.xlim{ind(i)}.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];

                    yllim_image = uiimage(           app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.panel,'Position',[app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.t1.Children(i).InnerPosition(1)-10, app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.t1.Children(i).InnerPosition(2), 20, app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.t1.Children(i).InnerPosition(4)]);
                    yllim_image.ImageSource = 'Red_vert_line.PNG';
                    yllim_image.UserData = ind(i);
                    yllim_image.ScaleMethod = 'scaledown';
                    yllim_image.ImageClickedFcn = @ImageClicked_lim_y;
                    app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.yleftlim{ind(i)} = yllim_image;
                    app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.ylim_normalized_pos{ind(i)} = app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.yleftlim{ind(i)}.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];

                    txa = uilabel(app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.panel,'Text','Rxn time','Position',[(app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.t1.Children(i).Position(1)+app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.t1.Children(i).Position(3))-65, app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.t1.Children(i).Position(2)+app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.t1.Children(i).Position(4)-3, 60, 20],'HorizontalAlignment','right'...
                        ,'FontSize',11);
                    app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.txt{ind(i)} = txa;
                    app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.txt_normalized_pos{ind(i)} = app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.txt{ind(i)}.Position./[app.UIFigure.Position(3:4) app.UIFigure.Position(3:4)];


                end
            end


            app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.t1.Units = 'normalized';

            function ImageClicked(src,event)
                delete(app.DialogApp);
                for i = 1 :  src.Parent.Parent.UserData.row* src.Parent.Parent.UserData.col
                    if i ~=  src.UserData
                        src.Parent.Parent.UserData.button{i}.Enable = 'on';
                    end
                end

                src.Parent.Parent.UserData.button{src.UserData}.Enable = 'off';
                app.DialogApp = dialogue_box(app,event.Source.Parent.Parent.UserData.Sourcenum,src.UserData);
                app.plot_num = src.UserData;

                app.DialogApp.EnterappropriatetitleTextArea.Value = src.Parent.Parent.UserData.Title{src.UserData};
                app.DialogApp.RefEcodeEditField_2.Value = src.Parent.Parent.UserData.Ref_Ecode_str{src.UserData};
                app.DialogApp.MarkEcodeEditField_2.Value  = src.Parent.Parent.UserData.Mark_Ecode_str{src.UserData};
                app.DialogApp.MustEcodeEditField_2.Value = src.Parent.Parent.UserData.Must_Ecode_str{src.UserData};
                app.DialogApp.MustNotEcodeEditField.Value  = src.Parent.Parent.UserData.MustNot_Ecode_str{src.UserData};
                app.DialogApp.ONEofthefollowingEcodesmustexistEditField.Value = src.Parent.Parent.UserData.One_Ecode_str{src.UserData};
            end
          
            function ImageClicked_lim_x(src,event)
                delete(app.Axis_dialogApp_x);
                for i = 1 : src.Parent.Parent.UserData.row* src.Parent.Parent.UserData.col
                    if i ~=  src.UserData
                        src.Parent.Parent.UserData.xlim{i}.Enable = 'on';
                        src.Parent.Parent.UserData.yleftlim{i}.Enable = 'on';
                    end
                end

                app.Axis_dialogApp_x = axis_dialogue_box(app,event.Source.Parent.Parent.UserData.Sourcenum,src.UserData);
                src.Parent.Parent.UserData.xlim{src.UserData}.Enable = 'off';
                src.Parent.Parent.UserData.yleftlim{src.UserData}.Enable = 'off';

                app.Axis_dialogApp_x.XUpperLimitEditField.Value = (src.Parent.Parent.UserData.axis_data{src.UserData}.x_upper_lim);
                app.Axis_dialogApp_x.XLowerLimitEditField.Value = (src.Parent.Parent.UserData.axis_data{src.UserData}.x_lower_lim);

            end

            function ImageClicked_lim_y(src,event)
                delete(app.Axis_dialogApp_y);
                for i = 1 : app.PlotDimrcSpinner.Value*app.PlotDimrcSpinner2.Value
                    if i ~=  src.UserData
                        src.Parent.Parent.UserData.xlim{i}.Enable = 'on';
                        src.Parent.Parent.UserData.yleftlim{i}.Enable = 'on';
                    end
                end

                app.Axis_dialogApp_y = dialogue_box_y(app,event.Source.Parent.Parent.UserData.Sourcenum,src.UserData);

                src.Parent.Parent.UserData.xlim{src.UserData}.Enable = 'off';
                src.Parent.Parent.UserData.yleftlim{src.UserData}.Enable = 'off';
                app.Axis_dialogApp_y.YUpperLimitEditField.Value = (src.Parent.Parent.UserData.axis_data{src.UserData}.yl_upper_lim);
                app.Axis_dialogApp_y.YLowerLimitEditField.Value = (src.Parent.Parent.UserData.axis_data{src.UserData}.yl_lower_lim);

            end


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.DialogApp)
                delete(app.DialogApp);
            end
            if ~isempty(app.Axis_dialogApp_x)
                delete(app.Axis_dialogApp_x);
            end
            if ~isempty(app.Axis_dialogApp_y)
                delete(app.Axis_dialogApp_y);
            end

            if app.outer_timer_running
                listOfTimers = timerfindall;
                if ~isempty(listOfTimers)
                    delete(listOfTimers(:));
                end
            end

            [status,result] = system('tasklist /FI "imagename eq trellis.exe" /fo table /nh');
            if ~isempty(strfind(result,'Trellis'))
                system('TASKKILL -f -im "Trellis.exe"');
            end

            delete(app);

        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)

            [status,result] = system('tasklist /FI "imagename eq trellis.exe" /fo table /nh');
            if isempty(app.save)
                app.save = 1;
            else
                if  app.save == 0
                    app.save = 1;
                else
                    app.save = 0;
                end
            end
            FilenameSuffixEditFieldValueChanging(app, event)
        end

        % Value changing function: FilenameSuffixEditField
        function FilenameSuffixEditFieldValueChanging(app, event)


        end

        % Image clicked function: Lock
        function LockImageClicked(app, event)
            if ~isempty(event.Source.Parent.UserData.Lock.UserData)
                if event.Source.Parent.UserData.Lock.UserData == 0
                    event.Source.ImageSource = "Lock.png";
                    event.Source.UserData = 1;
                    event.Source.Parent.UserData.Lock.UserData = 1;

                elseif event.Source.Parent.UserData.Lock.UserData == 1
                    event.Source.ImageSource  = "Unlock.png";
                    event.Source.UserData = 0;
                    event.Source.Parent.UserData.Lock.UserData = 0;


                end
            end
        end

        % Image clicked function: Image_fileopen_2
        function Image_fileopen_2Clicked(app, event)

            tmp = datetime('now');
            time = char(tmp);
            time = time(1:10);

            if isempty(app.FilenameSuffixEditField.Value)
                app.StatusLabel.Text = "Error: Set the suffix first!";
                app.StatusLabel.HorizontalAlignment ='right';
                app.StatusLabel.FontColor = [1 0 0];
            else
                 try
                    cd ([app.def_dir '\Raster_Data']);
                    if ~exist(time,'dir')
                        mkdir(time);
                        cd([pwd '\' time]);
                        app.Image_fileopen_2.UserData.dir =  pwd;
                        if exist(app.file_name)
                        app.Save_option = save_option(app);

                        cd(app.def_dir);
                        else
                        app.Image_fileopen_2.UserData.name = app.file_name;
                        event.Source.Parent.UserData.inst_save = 1;
                        cd(app.def_dir);
                       end
                       % [app.Image_fileopen_2.UserData.name,app.Image_fileopen_2.UserData.dir]=uiputfile('inst_data.mat','Saving instant data file...',[app.file_name '_inst']);

                    else
                        cd([pwd '\' time]);
                        app.Image_fileopen_2.UserData.dir =  pwd;
                        if exist(app.file_name)
                            app.Save_option = save_option(app);
                            app.Image_fileopen_2.UserData.name = app.file_name;
                            cd(app.def_dir);
                        else
                        app.Image_fileopen_2.UserData.name = app.file_name;
                        event.Source.Parent.UserData.inst_save = 1;
                        cd(app.def_dir);
                        end
                        % cd([pwd '\' time])
                       % [app.Image_fileopen_2.UserData.name,app.Image_fileopen_2.UserData.dir]=uiputfile('inst_data.mat','Saving instant data file...',[app.file_name '_inst']);
                        
                    end
                    event.Source.ImageSource = "file_save_select.PNG";

                    %if app.Image_fileopen_2.UserData.dir == 0
                    %    event.Source.Parent.UserData.inst_save = 0;
                    %end


                catch
                    app.StatusLabel.Text = "Error: Resetting the directory.";
                    app.StatusLabel.HorizontalAlignment ='right';
                    app.StatusLabel.FontColor = [1 0 0];
                    cd(app.def_dir);
                    event.Source.Parent.UserData.inst_save = 0;

                end

            end


        end

        % Image clicked function: Image_fileopen_3
        function Image_fileopen_3Clicked(app, event)
          if~ app.Rasters{app.Raster_Source}.UserData.Image_play.UserData
            try
                
                cd ([app.def_dir '\Raster_Data']);
                [file_name,dir] = uigetfile;
                cd (dir);
                app.input_data = load(file_name);
                
                event.Source.Parent.UserData.load = 1;
                load_config(app,event.Source.Parent.UserData.Sourcenum,app.input_data.inst_data.app_tmp)
                PlotRasterButtonPushed(app,event);
               % event.Source.Parent.UserData.load = 0;

               % event.Source.Parent.UserData.Eventtime = input_data.inst_data.Eventtime;
               % event.Source.Parent.UserData.Eventcode = input_data.inst_data.Eventcode;
               % event.Source.Parent.UserData.Spiketime = input_data.inst_data.Spiketime;
               % event.Source.Parent.UserData.Trial = input_data.inst_data.Trial;
                cd (app.def_dir);

            catch
                app.StatusLabel.Text = "Error: Select appropriate file!";
                app.StatusLabel.HorizontalAlignment ='right';
                app.StatusLabel.FontColor = [1 0 0];
                cd(app.def_dir);

            end
          else
               app.StatusLabel.Text = "Error: Pause the raster first!";
                app.StatusLabel.HorizontalAlignment ='right';
                app.StatusLabel.FontColor = [1 0 0];
                cd(app.def_dir);
          end

        end

        % Clicked callback: PushTool3
        function PushTool3Clicked(app, event)
            app.Rasters{app.Rasters{end}.UserData.Sourcenum+1} = copyobj(app.Raster_template,app.TabGroup,'legacy');
            UIFigureSizeChanged(app,event);
            app.Rasters{app.Rasters{end-1}.UserData.Sourcenum+1}.UserData.Sourcenum = app.Rasters{end-1}.UserData.Sourcenum+1;
            app.new_Raster = 1;
        end

        % Value changed function: ChSpinner
        function ChSpinnerValueChanged(app, event)
            app.Rasters{event.Source.Parent.UserData.Sourcenum}.UserData.ch = event.Value;

        end

        % Button pushed function: InstantstimgoButton
        function InstantstimgoButtonPushed(app, event)
           if app.Singlepulse1Block333usCheckBox.Value == 0
                     xippmex('stim',app.stimString);
          
           else
               xippmex('stimseq',app.stimString);
           end
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
         if ~isempty(app.Tabgroup_normalized_pos)
             app.TabGroup.Position(3:4) = app.Tabgroup_normalized_pos .* app.UIFigure.Position(3:4);
         end

         if app.app_initialized ==1
             for i = 1:length(app.Rasters)

                 offset = length(app.Rasters{i}.Children)-app.Raster_index.reference;
                 RI = cell2struct(num2cell([cellfun(@(x) x+offset, struct2cell(app.Raster_index))]),...
                     {'event_label','ch_num','plot_button','row','col','lock','play','file_open_config',...
                     'file_save_config','file_save_data','file_open_data','kernel','neural','behavioral','stop','plotdim','data','config','plotopt','ch_label','DAQ','refresh_text','refresh','reference'}); %label for Children; offset accounted
                 Fig_position = app.UIFigure.Position;
                 app.Rasters{i}.Children(RI.event_label).Position(2) = app.event_label_normalized_pos(2) .* Fig_position(4) ;
                 app.Rasters{i}.Children(RI.ch_num).Position(2) = app.ch_num_normalized_pos(2) .* Fig_position(4) ;
                 app.Rasters{i}.Children(RI.plot_button).Position(2) = app.plot_button_normalized_pos(2) .* Fig_position(4) ;
                 app.Rasters{i}.Children(RI.refresh).Position(2) = app.refresh_normalized_pos(2) .* Fig_position(4) ;
                 app.Rasters{i}.Children(RI.refresh_text).Position(2) = app.refreshtext_normalized_pos(2) .* Fig_position(4) ;
                 app.Rasters{i}.Children(RI.row).Position(2) = app.row_normalized_pos(2) .* Fig_position(4) ;
                 app.Rasters{i}.Children(RI.col).Position(2) = app.col_normalized_pos(2) .* Fig_position(4) ;
                 app.Rasters{i}.Children(RI.lock).Position(2) = app.lock_normalized_pos(2) .* Fig_position(4) ;
                 app.Rasters{i}.Children(RI.play).Position(2) = app.play_normalized_pos(2) .* Fig_position(4) ;
                 app.Rasters{i}.Children(RI.file_open_config).Position(2) = app.file_open_config_normalized_pos(2) .* Fig_position(4) ;
                 app.Rasters{i}.Children(RI.file_save_config).Position(2) = app.file_save_config_normalized_pos(2) .* Fig_position(4) ;
                 app.Rasters{i}.Children(RI.file_save_data).Position(2) = app.file_save_data_normalized_pos(2) .* Fig_position(4) ;
                 app.Rasters{i}.Children(RI.file_open_data).Position(2) = app.file_open_data_normalized_pos(2) .* Fig_position(4) ;
                 app.Rasters{i}.Children(RI.kernel).Position(2) = app.kernel_normalized_pos(2) .* Fig_position(4) ;
                 app.Rasters{i}.Children(RI.neural).Position(2) = app.neural_normalized_pos(2) .* Fig_position(4) ;
                 app.Rasters{i}.Children(RI.behavioral).Position(2) = app.behavioral_normalized_pos(2) .* Fig_position(4) ;
                 app.Rasters{i}.Children(RI.stop).Position(2) = app.stop_normalized_pos(2) .* Fig_position(4) ;
                 app.Rasters{i}.Children(RI.plotdim).Position(2) = app.plotdim_normalized_pos(2) .* Fig_position(4) ;
                 app.Rasters{i}.Children(RI.data).Position(2) = app.data_normalized_pos(2) .* Fig_position(4) ;
                 app.Rasters{i}.Children(RI.config).Position(2) = app.config_normalized_pos(2) .* Fig_position(4) ;
                 app.Rasters{i}.Children(RI.plotopt).Position(2) = app.plotopt_normalized_pos(2) .* Fig_position(4) ;
                 app.Rasters{i}.Children(RI.ch_label).Position(2) = app.ch_label_normalized_pos(2) .* Fig_position(4) ;
                 app.Rasters{i}.Children(RI.DAQ).Position(2) = app.DAQ_normalized_pos(2) .* Fig_position(4) ;
                 app.StatusLabel.Position(2) = app.StatusLabel_normalized_pos(2) .*Fig_position(4) ;
                 app.FilenameSuffixEditField.Position(2) = app.FilenameSuffixEditField_normalized_pos(2) .*Fig_position(4) ;
                 app.FilenameSuffixEditFieldLabel.Position(2) = app.FilenameSuffixEditFieldLabel_normalized_pos(2) .*Fig_position(4) ;
                 app.FilenameSuffixEditFieldLabel_3.Position(2)= app.FilenameSuffixEditFieldLabel_3_normalized_pos(2) .*Fig_position(4) ;
                 app.Spinner.Position(2) = app.Spinner_normalized_pos(2) .*Fig_position(4) ;
                 app.SpinnerLabel.Position(2) = app.SpinnerLabel_normalized_pos(2) .*Fig_position(4);
                 if app.Rasters{i}.UserData.PlotRasterButton.UserData.firstplot
                     % temp = app.Rasters{i}.UserData.t1.Children
                     app.Rasters{i}.UserData.panel.Position(3:4) = app.Rasters{i}.UserData.Panel_normalized_pos .* Fig_position(3:4);

                     yleftlim_pos = arrayfun(@(x) [x.InnerPosition(1)-10 x.InnerPosition(2),20,x.InnerPosition(4)],app.Rasters{i}.UserData.t1.Children,'UniformOutput',false);
                     xlim_pos =   arrayfun(@(x) [x.InnerPosition(1) x.InnerPosition(2)-10,x.InnerPosition(3),20],app.Rasters{i}.UserData.t1.Children,'UniformOutput',false);
                     button_pos =    arrayfun(@(x) [(x.Position(1)+x.Position(3)-28),(x.Position(2)+x.Position(4)-25), 20, 20],app.Rasters{i}.UserData.t1.Children,'UniformOutput',false);
                     txt_pos =    arrayfun(@(x) [(x.Position(1)+x.Position(3)-60),(x.Position(2)+x.Position(4)-3), 60, 20],app.Rasters{i}.UserData.t1.Children,'UniformOutput',false);

                     for ii = 1:length(app.Rasters{i}.UserData.button)
                         ind = [length(app.Rasters{i}.UserData.button):-1:1];
                         app.Rasters{i}.UserData.yleftlim{ii}.Position = [yleftlim_pos{ind(ii)}];%[app.Rasters{i}.UserData.t1.Children(ii).InnerPosition(1)-10, ...
                         %   app.Rasters{i}.UserData.t1.Children(ii).InnerPosition(2), 20, app.Rasters{i}.UserData.t1.Children(ii).InnerPosition(4)];

                         app.Rasters{i}.UserData.xlim{ii}.Position = [xlim_pos{ind(ii)}]; %[app.Rasters{i}.UserData.t1.Children(ii).InnerPosition(1), ...
                         %  app.Rasters{i}.UserData.t1.Children(ii).InnerPosition(2)-10,app.Rasters{i}.UserData.t1.Children(ii).InnerPosition(3),20];

                         app.Rasters{i}.UserData.button{ii}.Position = [button_pos{ind(ii)}]; %[(app.Rasters{i}.UserData.t1.Children(ii).Position(1)+app.Rasters{i}.UserData.t1.Children(ii).Position(3))-28,...
                         %app.Rasters{i}.UserData.t1.Children(ii).Position(2)+app.Rasters{i}.UserData.t1.Children(ii).Position(4)-25, 20, 20];

                         app.Rasters{i}.UserData.txt{ii}.Position = [txt_pos{ind(ii)}]; %[(app.Rasters{i}.UserData.t1.Children(ii).Position(1)+app.Rasters{i}.UserData.t1.Children(ii).Position(3))-28,...

                     end

                 end
             end

             app.Image2.Position= app.Image2_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.StartECODESpinner.Position = app.StartECODESpinner_normalized_pos.* [Fig_position(3:4) Fig_position(3:4)];
             app.EndECODESpinner.Position = app.EndECODESpinner_normalized_pos.* [Fig_position(3:4) Fig_position(3:4)];
             app.RunTrelliswithDefaultSettingsButton.Position = app.RunTrelliswithDefaultSettingsButton_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.V211026YSRLabel.Position= app.V211026YSRLabel_normalized_pos.* [Fig_position(3:4) Fig_position(3:4)];
             app.Image7.Position = app.Image7_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.StartECODESpinnerLabel.Position = app.StartECODESpinnerLabel_normalized_pos.* [Fig_position(3:4) Fig_position(3:4)];
             app.EndECODESpinnerLabel.Position = app.EndECODESpinnerLabel_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             
             app.TypeappropriatevaluesSinglechonlyLabel.Position = app.TypeappropriatevaluesSinglechonlyLabel_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.Singlepulse1Block333usCheckBox.Position = app.Singlepulse1Block333usCheckBox_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.StimElectrodesTextArea.Position = app.StimElectrodesTextArea_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.TrainLengthmsTextArea.Position = app.TrainLengthmsTextArea_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.FrequencyHzTextArea.Position = app.FrequencyHzTextArea_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.CathDurationmsTextArea.Position = app.CathDurationmsTextArea_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.AnodDurationmsTextArea.Position = app.AnodDurationmsTextArea_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.CathAmplitudeuATextArea.Position = app.CathAmplitudeuATextArea_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.AnodAmplitudeuATextArea.Position = app.AnodAmplitudeuATextArea_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.DelaymsTextArea.Position = app.DelaymsTextArea_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.PolaritybinaryTextArea.Position = app.PolaritybinaryTextArea_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.CheckifstimulusisvalidButton.Position = app.CheckifstimulusisvalidButton_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.WaveformLabel.Position = app.WaveformLabel_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.NoerrorandplottingisstandbyLabel.Position = app.NoerrorandplottingisstandbyLabel_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.UIAxes.Position = app.UIAxes_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.StimcurrentlystandbyLabel.Position = app.StimcurrentlystandbyLabel_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.Image.Position = app.Image_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.StimstandbyButton.Position = app.StimstandbyButton_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.InstantstimgoButton.Position = app.InstantstimgoButton_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.Label_3.Position = app.Label_3_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.Label_2.Position = app.Label_2_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.Label.Position = app.Label_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.RecChSpinner.Position = app.RecChSpinner_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.StimdelaySpinner.Position = app.StimdelaySpinner_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.AmplitudeSpinner.Position= app.AmplitudeSpinner_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.StimChSpinner.Position= app.StimChSpinner_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.RefractorydurSpinner.Position= app.RefractorydurSpinner_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.DurationSpinner.Position= app.DurationSpinner_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.UnitDropDown.Position= app.UnitDropDown_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.PlayoptionLabel.Position= app.PlayoptionLabel_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.DataLabel_2.Position= app.DataLabel_2_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.RefreshLabel_2.Position= app.RefreshLabel_2_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.Image_play_2.Position= app.Image_play_2_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.Image_stop_2.Position= app.Image_stop_2_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.Image_fileopen_4.Position= app.Image_fileopen_4_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.Image_fileopen_5.Position= app.Image_fileopen_5_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.Image8_2.Position= app.Image8_2_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.UIAxes_2.Position= app.UIAxes_2_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.SliderforStimDelay.Position= app.SliderforStimDelay_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];
             app.ReferenceSlider.Position= app.ReferenceSlider_normalized_pos .* [Fig_position(3:4) Fig_position(3:4)];

         end
         
        end

        % Image clicked function: Image8
        function Image8Clicked(app, event)
            event.Source.Parent.UserData.Refresh = 1;
            if event.Source.Parent.UserData.Refresh == 1
            event.Source.ImageSource = "refresh_select.PNG";
            end
        end

        % Value changed function: FilenameSuffixEditField
        function FilenameSuffixEditFieldValueChanged(app, event)
                tmp = datetime('now');
            time = char(tmp);
            time = time(1:10);
            pause(0.1);
            tmp = app.FilenameSuffixEditField.Value;
            num = num2str(app.Spinner.Value);
            if isempty(tmp)
                app.file_name = ["YYYY-MM-DD-#"];
            else
                app.file_name = [tmp '_' time '_' num '.mat'];
            end

                app.FilenameSuffixEditFieldLabel_3.Text = [app.file_name];
            
%}
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            tmp = datetime('now');
            time = char(tmp);
            time = time(1:10);
            pause(0.1);
            tmp = app.FilenameSuffixEditField.Value;
            num = num2str(app.Spinner.Value);
            if isempty(tmp)
                app.file_name = ["YYYY-MM-DD-#"];
            else
                app.file_name = [tmp '_' time '_' num '.mat'];
            end


            app.FilenameSuffixEditFieldLabel_3.Text = [app.file_name];

            %{
            if isempty(app.save)
                app.FilenameSuffixEditFieldLabel_3.Text = [app.file_name  ' is standby'];
            else
                if app.save == 0
                    app.FilenameSuffixEditFieldLabel_3.Text = [app.file_name ' is standby'];

                else
                    app.FilenameSuffixEditFieldLabel_3.Text = [app.file_name ' is being saved'];

                end
            end
%}
        end

        % Value changed function: RecChSpinner
        function RecChSpinnerValueChanged(app, event)
            
        end

        % Value changed function: StimChSpinner
        function StimChSpinnerValueChanged(app, event)
            
        end

        % Value changed function: StimdelaySpinner
        function StimdelaySpinnerValueChanged(app, event)
                    if ~isempty(app.delayline)
            delete(app.delayline);
            end
            app.delayline = xline(app.UIAxes_2,-1*app.StimdelaySpinner.Value);
            app.SliderforStimDelay.Value = -1*app.StimdelaySpinner.Value;

        end

        % Value changed function: RefractorydurSpinner
        function RefractorydurSpinnerValueChanged(app, event)
            
        end

        % Value changed function: UnitDropDown
        function UnitDropDownValueChanged(app, event)
            value = app.UnitDropDown.Value;
            if strcmp(value,'Magenta')
                app.unit = 1;
            elseif strcmp(value,'Orange')
                app.unit = 2;
            elseif strcmp(value,'Green')
                app.unit = 3;
            elseif strcmp(value,'Blue')
                app.unit = 1;
            elseif strcmp(value,'Unsorted')
                app.unit=0;
            end

            
        end

        % Image clicked function: Image_play_2
        function Image_play_2Clicked(app, event)
            app.Anti_end = 0;
            app.Anti_running = 1;
            app.Image_stop_2.ImageSource = 'stop_icon.PNG';
            app.Image_play_2.ImageSource = 'play_select.PNG';
        end

        % Image clicked function: Image_stop_2
        function Image_stop_2Clicked(app, event)
            app.Anti_running = 0;
            app.Anti_end = 1;
            app.Image_play_2.ImageSource = 'play_icon.PNG';
            app.Image_stop_2.ImageSource = 'stop_select.PNG';

        end

        % Value changed function: AmplitudeSpinner
        function AmplitudeSpinnerValueChanged(app, event)
            value = app.AmplitudeSpinner.Value;
            
        end

        % Value changed function: SliderforStimDelay
        function SliderforStimDelayValueChanged(app, event)
            if ~isempty(app.delayline)
            delete(app.delayline);
            end
            app.delayline = xline(app.UIAxes_2,app.SliderforStimDelay.Value);
            app.StimdelaySpinner.Value = -1*app.SliderforStimDelay.Value;
        end

        % Value changed function: ReferenceSlider
        function ReferenceSliderValueChanged(app, event)
            if ~isempty(app.referenceline)
                delete(app.referenceline);
            end
            app.referenceline = xline(app.UIAxes_2,app.ReferenceSlider.Value);

        end

        % Image clicked function: Image8_2
        function Image8_2Clicked(app, event)
            app.Anti_refresh = 1;
            app.Image8_2.ImageSource = "refresh_select.PNG";

        end

        % Image clicked function: Image9
        function Image9Clicked(app, event)
            app.x_axis_dialogue = axis_dialogue_box_anti(app);

        end

        % Image clicked function: Image10
        function Image10Clicked(app, event)
            app.y_axis_dialogue = dialogue_box_y_anti(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = 'instant_file_save.png';
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = 'add_raster.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.V211026YSRLabel = uilabel(app.MainTab);
            app.V211026YSRLabel.Text = {'V. 211026'; 'Y.S.R'};
            app.Image7 = uiimage(app.MainTab);
            app.Image7.ImageSource = 'SNU.png';
            app.EndECODESpinner = uispinner(app.MainTab);
            app.EndECODESpinnerLabel = uilabel(app.MainTab);
            app.EndECODESpinnerLabel.Text = 'End ECODE';
            app.StartECODESpinner = uispinner(app.MainTab);
            app.StartECODESpinnerLabel = uilabel(app.MainTab);
            app.StartECODESpinnerLabel.Text = 'Start ECODE';
            app.RunTrelliswithDefaultSettingsButton = uibutton(app.MainTab, 'push');
            app.RunTrelliswithDefaultSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @RunTrelliswithDefaultSettingsButtonPushed, true);
            app.RunTrelliswithDefaultSettingsButton.Text = {'Run Trellis '; 'with'; 'Default Settings'};
            app.Image2 = uiimage(app.MainTab);
            app.Image2.ImageSource = 'cocila2 (1).jpg';
            app.StimTab = uitab(app.TabGroup);
            app.StimTab.Title = 'Stim';
            app.UIAxes = uiaxes(app.StimTab);
            xlabel(app.UIAxes, 'Time (ms)')
            ylabel(app.UIAxes, 'Voltage (mv)')
            zlabel(app.UIAxes, 'Z')
            app.StimstandbyButton = uibutton(app.StimTab, 'push');
            app.StimstandbyButton.ButtonPushedFcn = createCallbackFcn(app, @StimstandbyButtonPushed, true);
            app.StimstandbyButton.Text = 'Stim standby!';
            app.Image = uiimage(app.StimTab);
            app.Image.ImageSource = '피카츄_대기.png';
            app.StimElectrodesTextArea = uitextarea(app.StimTab);
            app.StimElectrodesTextArea.ValueChangedFcn = createCallbackFcn(app, @StimElectrodesTextAreaValueChanged, true);
            app.StimElectrodesTextAreaLabel = uilabel(app.StimTab);
            app.StimElectrodesTextAreaLabel.Text = 'Stim Electrodes';
            app.TrainLengthmsTextAreaLabel = uilabel(app.StimTab);
            app.TrainLengthmsTextAreaLabel.Text = 'Train Length (ms)';
            app.TrainLengthmsTextArea = uitextarea(app.StimTab);
            app.TrainLengthmsTextArea.ValueChangedFcn = createCallbackFcn(app, @TrainLengthmsTextAreaValueChanged, true);
            app.FrequencyHzTextAreaLabel = uilabel(app.StimTab);
            app.FrequencyHzTextAreaLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzTextArea = uitextarea(app.StimTab);
            app.FrequencyHzTextArea.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzTextAreaValueChanged, true);
            app.NoerrorandplottingisstandbyLabel = uilabel(app.StimTab);
            app.NoerrorandplottingisstandbyLabel.Text = 'No error and plotting is standby';
            app.StimcurrentlystandbyLabel = uilabel(app.StimTab);
            app.StimcurrentlystandbyLabel.Text = 'Stim currently standby';
            app.CathDurationmsTextArea = uitextarea(app.StimTab);
            app.CathDurationmsTextArea.ValueChangedFcn = createCallbackFcn(app, @CathDurationmsTextAreaValueChanged, true);
            app.CathDurationmsTextAreaLabel = uilabel(app.StimTab);
            app.CathDurationmsTextAreaLabel.Text = 'Cath Duration (ms)';
            app.AnodDurationmsTextAreaLabel = uilabel(app.StimTab);
            app.AnodDurationmsTextAreaLabel.Text = 'Anod Duration (ms)';
            app.AnodDurationmsTextArea = uitextarea(app.StimTab);
            app.AnodDurationmsTextArea.ValueChangedFcn = createCallbackFcn(app, @AnodDurationmsTextAreaValueChanged, true);
            app.CathAmplitudeuATextAreaLabel = uilabel(app.StimTab);
            app.CathAmplitudeuATextAreaLabel.Text = 'Cath Amplitude (uA)';
            app.CathAmplitudeuATextArea = uitextarea(app.StimTab);
            app.CathAmplitudeuATextArea.ValueChangedFcn = createCallbackFcn(app, @CathAmplitudeuATextAreaValueChanged, true);
            app.AnodAmplitudeuATextAreaLabel = uilabel(app.StimTab);
            app.AnodAmplitudeuATextAreaLabel.Text = 'Anod Amplitude (uA)';
            app.AnodAmplitudeuATextArea = uitextarea(app.StimTab);
            app.AnodAmplitudeuATextArea.ValueChangedFcn = createCallbackFcn(app, @AnodAmplitudeuATextAreaValueChanged, true);
            app.DelaymsTextAreaLabel = uilabel(app.StimTab);
            app.DelaymsTextAreaLabel.Text = 'Delay (ms)';
            app.DelaymsTextArea = uitextarea(app.StimTab);
            app.DelaymsTextArea.ValueChangedFcn = createCallbackFcn(app, @DelaymsTextAreaValueChanged, true);
            app.PolaritybinaryTextAreaLabel = uilabel(app.StimTab);
            app.PolaritybinaryTextAreaLabel.Text = 'Polarity (binary)';
            app.PolaritybinaryTextArea = uitextarea(app.StimTab);
            app.PolaritybinaryTextArea.ValueChangedFcn = createCallbackFcn(app, @PolaritybinaryTextAreaValueChanged, true);
            app.CheckifstimulusisvalidButton = uibutton(app.StimTab, 'push');
            app.CheckifstimulusisvalidButton.ButtonPushedFcn = createCallbackFcn(app, @CheckifstimulusisvalidButtonPushed, true);
            app.CheckifstimulusisvalidButton.Text = 'Check if stimulus is valid ';
            app.InstantstimgoButton = uibutton(app.StimTab, 'push');
            app.InstantstimgoButton.ButtonPushedFcn = createCallbackFcn(app, @InstantstimgoButtonPushed, true);
            app.InstantstimgoButton.Text = {'Instant '; 'stim'; 'go!'};
            app.Singlepulse1Block333usCheckBox = uicheckbox(app.StimTab);
            app.Singlepulse1Block333usCheckBox.ValueChangedFcn = createCallbackFcn(app, @Singlepulse1Block333usCheckBoxValueChanged, true);
            app.Singlepulse1Block333usCheckBox.Text = 'Single pulse (1 Block = 33.3 us)';
            app.WaveformLabel = uilabel(app.StimTab);
            app.WaveformLabel.Text = 'Waveform ';
            app.TypeappropriatevaluesSinglechonlyLabel = uilabel(app.StimTab);
            app.TypeappropriatevaluesSinglechonlyLabel.Text = 'Type appropriate values (Single ch. only)';
            app.Label = uilabel(app.StimTab);
            app.Label_2 = uilabel(app.StimTab);
            app.Label_3 = uilabel(app.StimTab);
            app.RasterTab = uitab(app.TabGroup);
            app.RasterTab.Title = 'Raster';
            app.NoeventdetectedLabel = uilabel(app.RasterTab);
            app.NoeventdetectedLabel.Text = 'No event detected';
            app.DAQStatusLabel = uilabel(app.RasterTab);
            app.DAQStatusLabel.Text = 'DAQ Status';
            app.ChSpinnerLabel = uilabel(app.RasterTab);
            app.ChSpinnerLabel.Text = 'Ch. #';
            app.Lock = uiimage(app.RasterTab);
            app.Lock.ImageClickedFcn = createCallbackFcn(app, @LockImageClicked, true);
            app.Lock.ImageSource = 'Lock.PNG';
            app.ChSpinner = uispinner(app.RasterTab);
            app.ChSpinner.ValueChangedFcn = createCallbackFcn(app, @ChSpinnerValueChanged, true);
            app.ChSpinner.Value = 1;
            app.Image_fileopen_2 = uiimage(app.RasterTab);
            app.Image_fileopen_2.ImageClickedFcn = createCallbackFcn(app, @Image_fileopen_2Clicked, true);
            app.Image_fileopen_2.ImageSource = 'file_save_icon.PNG';
            app.PlotRasterButton = uibutton(app.RasterTab, 'push');
            app.PlotRasterButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRasterButtonPushed, true);
            app.PlotRasterButton.Text = {'Plot '; 'Raster!'};
            app.PlotDimrcSpinner2 = uispinner(app.RasterTab);
            app.PlotDimrcSpinner2.ValueChangedFcn = createCallbackFcn(app, @PlotDimrcSpinner2ValueChanged, true);
            app.PlotDimrcSpinner2.Value = 1;
            app.PlotDimrcSpinner = uispinner(app.RasterTab);
            app.PlotDimrcSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotDimrcSpinnerValueChanged, true);
            app.PlotDimrcSpinner.Value = 1;
            app.Image_fileopen_3 = uiimage(app.RasterTab);
            app.Image_fileopen_3.ImageClickedFcn = createCallbackFcn(app, @Image_fileopen_3Clicked, true);
            app.Image_fileopen_3.ImageSource = 'file_open_icon.PNG';
            app.PlotOptionsLabel = uilabel(app.RasterTab);
            app.PlotOptionsLabel.Text = 'Plot Options';
            app.ConfigurationLabel = uilabel(app.RasterTab);
            app.ConfigurationLabel.Text = 'Configuration';
            app.DataLabel = uilabel(app.RasterTab);
            app.DataLabel.Text = 'Data';
            app.Image_play = uiimage(app.RasterTab);
            app.Image_play.ImageClickedFcn = createCallbackFcn(app, @Image_playClicked, true);
            app.Image_play.ImageSource = 'play_icon.PNG';
            app.Image_fileopen = uiimage(app.RasterTab);
            app.Image_fileopen.ImageClickedFcn = createCallbackFcn(app, @Image_fileopenClicked, true);
            app.Image_fileopen.ImageSource = 'file_open_icon.PNG';
            app.Image_stop = uiimage(app.RasterTab);
            app.Image_stop.ImageClickedFcn = createCallbackFcn(app, @Image_stopClicked, true);
            app.Image_stop.ImageSource = 'stop_select.png';
            app.Image_filesave = uiimage(app.RasterTab);
            app.Image_filesave.ImageClickedFcn = createCallbackFcn(app, @Image_filesaveClicked, true);
            app.Image_filesave.ImageSource = 'file_save_icon.PNG';
            app.Image_behavioral = uiimage(app.RasterTab);
            app.Image_behavioral.ImageClickedFcn = createCallbackFcn(app, @Image_behavioralClicked, true);
            app.Image_behavioral.ImageSource = 'behavior_icon.PNG';
            app.Image_neural = uiimage(app.RasterTab);
            app.Image_neural.ImageClickedFcn = createCallbackFcn(app, @Image_neuralClicked, true);
            app.Image_neural.ImageSource = 'neural_icon.PNG';
            app.Image_kernel = uiimage(app.RasterTab);
            app.Image_kernel.ImageClickedFcn = createCallbackFcn(app, @Image_kernelClicked, true);
            app.Image_kernel.ImageSource = 'kernel_icon.PNG';
            app.PlotDimrcSpinnerLabel = uilabel(app.RasterTab);
            app.PlotDimrcSpinnerLabel.Text = 'Plot Dim (r,c)';
            app.Image8 = uiimage(app.RasterTab);
            app.Image8.ImageClickedFcn = createCallbackFcn(app, @Image8Clicked, true);
            app.Image8.ImageSource = 'refresh_icon.png';
            app.RefreshLabel = uilabel(app.RasterTab);
            app.RefreshLabel.Text = 'Refresh';
            app.AntiTab = uitab(app.TabGroup);
            app.AntiTab.Title = 'Anti';
            app.UIAxes_2 = uiaxes(app.AntiTab);
            xlabel(app.UIAxes_2, 'Time (ms)')
            ylabel(app.UIAxes_2, 'Voltage (mv)')
            zlabel(app.UIAxes_2, 'Z')
            app.SliderforStimDelay = uislider(app.AntiTab);
            app.SliderforStimDelay.ValueChangedFcn = createCallbackFcn(app, @SliderforStimDelayValueChanged, true);
            app.UnitDropDownLabel = uilabel(app.AntiTab);
            app.UnitDropDownLabel.Text = 'Unit';
            app.UnitDropDown = uidropdown(app.AntiTab);
            app.UnitDropDown.Items = {'Not sorted', 'Magenta', 'Orange', 'Green', 'Blue'};
            app.UnitDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitDropDownValueChanged, true);
            app.UnitDropDown.Value = 'Not sorted';
            app.RecChSpinnerLabel = uilabel(app.AntiTab);
            app.RecChSpinnerLabel.Text = 'Rec. Ch # ';
            app.RecChSpinner = uispinner(app.AntiTab);
            app.RecChSpinner.ValueChangedFcn = createCallbackFcn(app, @RecChSpinnerValueChanged, true);
            app.RecChSpinner.Value = 1;
            app.StimChSpinnerLabel = uilabel(app.AntiTab);
            app.StimChSpinnerLabel.Text = 'Stim Ch #';
            app.StimChSpinner = uispinner(app.AntiTab);
            app.StimChSpinner.ValueChangedFcn = createCallbackFcn(app, @StimChSpinnerValueChanged, true);
            app.StimChSpinner.Value = 2;
            app.RefractorydurSpinnerLabel = uilabel(app.AntiTab);
            app.RefractorydurSpinnerLabel.Text = 'Refractory dur.';
            app.RefractorydurSpinner = uispinner(app.AntiTab);
            app.RefractorydurSpinner.ValueChangedFcn = createCallbackFcn(app, @RefractorydurSpinnerValueChanged, true);
            app.RefractorydurSpinner.Value = 2000;
            app.StimdelaySpinnerLabel = uilabel(app.AntiTab);
            app.StimdelaySpinnerLabel.Text = 'Stim delay';
            app.StimdelaySpinner = uispinner(app.AntiTab);
            app.StimdelaySpinner.ValueChangedFcn = createCallbackFcn(app, @StimdelaySpinnerValueChanged, true);
            app.StimdelaySpinner.Value = 2000;
            app.Image_fileopen_4 = uiimage(app.AntiTab);
            app.Image_fileopen_4.ImageSource = 'file_save_icon.PNG';
            app.Image_fileopen_5 = uiimage(app.AntiTab);
            app.Image_fileopen_5.ImageSource = 'file_open_icon.PNG';
            app.Image8_2 = uiimage(app.AntiTab);
            app.Image8_2.ImageClickedFcn = createCallbackFcn(app, @Image8_2Clicked, true);
            app.Image8_2.ImageSource = 'refresh_icon.png';
            app.RefreshLabel_2 = uilabel(app.AntiTab);
            app.RefreshLabel_2.Text = 'Refresh';
            app.DataLabel_2 = uilabel(app.AntiTab);
            app.DataLabel_2.Text = 'Data';
            app.Image_play_2 = uiimage(app.AntiTab);
            app.Image_play_2.ImageClickedFcn = createCallbackFcn(app, @Image_play_2Clicked, true);
            app.Image_play_2.ImageSource = 'play_icon.PNG';
            app.Image_stop_2 = uiimage(app.AntiTab);
            app.Image_stop_2.ImageClickedFcn = createCallbackFcn(app, @Image_stop_2Clicked, true);
            app.Image_stop_2.ImageSource = 'stop_select.png';
            app.PlayoptionLabel = uilabel(app.AntiTab);
            app.PlayoptionLabel.Text = 'Play option';
            app.AmplitudeSpinnerLabel = uilabel(app.AntiTab);
            app.AmplitudeSpinnerLabel.Text = 'Amplitude';
            app.AmplitudeSpinner = uispinner(app.AntiTab);
            app.AmplitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSpinnerValueChanged, true);
            app.DurationSpinnerLabel = uilabel(app.AntiTab);
            app.DurationSpinnerLabel.Text = 'Duration';
            app.DurationSpinner = uispinner(app.AntiTab);
            app.SliderforStimDelayLabel = uilabel(app.AntiTab);
            app.SliderforStimDelayLabel.Text = 'Slider for Stim Delay ';
            app.ReferenceSliderLabel = uilabel(app.AntiTab);
            app.ReferenceSliderLabel.Text = 'Reference Slider';
            app.ReferenceSlider = uislider(app.AntiTab);
            app.ReferenceSlider.ValueChangedFcn = createCallbackFcn(app, @ReferenceSliderValueChanged, true);
            app.Image9 = uiimage(app.AntiTab);
            app.Image9.ImageClickedFcn = createCallbackFcn(app, @Image9Clicked, true);
            app.Image9.ImageSource = 'Red_horz_line.png';
            app.Image10 = uiimage(app.AntiTab);
            app.Image10.ImageClickedFcn = createCallbackFcn(app, @Image10Clicked, true);
            app.Image10.ImageSource = 'Red_vert_line.PNG';
            app.FilenameSuffixEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameSuffixEditFieldLabel.Text = 'File name | Suffix: ';
            app.FilenameSuffixEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameSuffixEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameSuffixEditFieldValueChanged, true);
            app.FilenameSuffixEditField.ValueChangingFcn = createCallbackFcn(app, @FilenameSuffixEditFieldValueChanging, true);
            app.FilenameSuffixEditFieldLabel_3 = uilabel(app.UIFigure);
            app.FilenameSuffixEditFieldLabel_3.Text = 'YYYY-MM-DD-#';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = '#';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SNUCOCILARIPPLE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end