classdef axis_dialogue_box < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ApplyallCheckBox           matlab.ui.control.CheckBox
        XAxisSetupLabel            matlab.ui.control.Label
        ApplyButton                matlab.ui.control.Button
        XUpperLimitEditField       matlab.ui.control.NumericEditField
        XUpperLimitEditFieldLabel  matlab.ui.control.Label
        XLowerLimitEditField       matlab.ui.control.NumericEditField
        XLowerLimitEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp; % Description
    end
    
    properties (Access = public)
        Raster_Sourcenum; % Description
                plot_num;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Raster_Sourcenum, plot_num)
            app.CallingApp = mainapp;
            app.Raster_Sourcenum = Raster_Sourcenum;
            app.plot_num = plot_num;
            app.UIFigure.Position = [app.CallingApp.UIFigure.Position(1) app.CallingApp.UIFigure.Position(2) 232 171];
            if ~isempty(app.CallingApp.x_past_dialogue_ind)
                app.CallingApp.Rasters{app.CallingApp.y_past_dialogue_ind(1)}.UserData.yleftlim{app.CallingApp.y_past_dialogue_ind(2)}.Enable = 'on';
                app.CallingApp.Rasters{app.CallingApp.y_past_dialogue_ind(1)}.UserData.xlim{app.CallingApp.y_past_dialogue_ind(2)}.Enable = 'on';
            end

            app.CallingApp.x_past_dialogue_ind = [app.Raster_Sourcenum app.plot_num];

            app.ApplyallCheckBox.Value = 0;

        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            
            
            updateplot_axis_x(app.CallingApp,app.XLowerLimitEditField.Value,app.XUpperLimitEditField.Value,app.plot_num,app.ApplyallCheckBox.Value)
            app.CallingApp.Rasters{app.Raster_Sourcenum}.UserData.yleftlim{app.plot_num}.Enable = 'on';
            app.CallingApp.Rasters{app.Raster_Sourcenum}.UserData.xlim{app.plot_num}.Enable = 'on';
            app.CallingApp.Rasters{app.Raster_Sourcenum}.UserData.x_lock(app.plot_num) = 0;
            if app.ApplyallCheckBox.Value
                for i =1:length(app.CallingApp.Rasters{app.Raster_Sourcenum}.UserData.x_lock)
                app.CallingApp.Rasters{app.Raster_Sourcenum}.UserData.x_lock(i) = 0;
                end
            end


         %   offset = length(app.CallingApp.Rasters{app.Raster_Sourcenum}.Children)-app.CallingApp.Raster_index.reference;
         %   RI = cell2struct(num2cell([cellfun(@(x) x+offset, struct2cell(app.CallingApp.Raster_index))]),...
         %           {'event_label','ch_num','plot_button','row','col','lock','play','file_open_config',...
         %       'file_save_config','file_save_data','file_open_data','kernel','neural','behavioral','stop','plotdim','data','config','plotopt','ch_label','DAQ','refresh_text','refresh','reference'}); %label for Children; offset accounted


      %      app.CallingApp.Rasters{app.Raster_Sourcenum}.Children(RI.lock).UserData = 0;
      %      app.CallingApp.Rasters{app.Raster_Sourcenum}.UserData.Lock.UserData = 0;
      %      app.CallingApp.Rasters{app.Raster_Sourcenum}.Children(RI.lock).ImageSource  = "Unlock.png";

            delete(app);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.Rasters{app.Raster_Sourcenum}.UserData.xlim{app.plot_num}.Enable = 'on';
            app.CallingApp.Rasters{app.Raster_Sourcenum}.UserData.yleftlim{app.plot_num}.Enable = 'on';

            delete(app);
        end

        % Value changed function: XLowerLimitEditField
        function XLowerLimitEditFieldValueChanged(app, event)
            value = app.XLowerLimitEditField.Value;
            
        end

        % Value changed function: XUpperLimitEditField
        function XUpperLimitEditFieldValueChanged(app, event)
            value = app.XUpperLimitEditField.Value;
            
        end

        % Value changed function: ApplyallCheckBox
        function ApplyallCheckBoxValueChanged(app, event)
            value = app.ApplyallCheckBox.Value;
            app.CallingApp.select_all_x = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.XLowerLimitEditFieldLabel = uilabel(app.UIFigure);
            app.XLowerLimitEditFieldLabel.Text = 'X Lower Limit';
            app.XLowerLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.XLowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @XLowerLimitEditFieldValueChanged, true);
            app.XUpperLimitEditFieldLabel = uilabel(app.UIFigure);
            app.XUpperLimitEditFieldLabel.Text = 'X Upper Limit';
            app.XUpperLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.XUpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @XUpperLimitEditFieldValueChanged, true);
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply!';
            app.XAxisSetupLabel = uilabel(app.UIFigure);
            app.XAxisSetupLabel.Text = 'X Axis Set-up';
            app.ApplyallCheckBox = uicheckbox(app.UIFigure);
            app.ApplyallCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplyallCheckBoxValueChanged, true);
            app.ApplyallCheckBox.Text = 'Apply all';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = axis_dialogue_box(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end