classdef axis_dialogue_box_anti < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        XAxisSetupLabel       matlab.ui.control.Label
        ApplyButton           matlab.ui.control.Button
        DomainEditField       matlab.ui.control.NumericEditField
        DomainEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp; % Description
    end
    
    properties (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            app.DomainEditField.Value = app.CallingApp.UIAxes_2.XLim(2)*2;
          
            app.UIFigure.Position = [app.CallingApp.UIFigure.Position(1) app.CallingApp.UIFigure.Position(2) 232 171];

        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            
            if app.DomainEditField.Value > 0
                app.CallingApp.UIAxes_2.XLim = [-app.DomainEditField.Value/2 app.DomainEditField.Value/2];
                app.CallingApp.SliderforStimDelay.Limits = [app.CallingApp.UIAxes_2.XLim(1) 0];
                app.CallingApp.ReferenceSlider.Limits = [0 app.CallingApp.UIAxes_2.XLim(2)];

            else
                app.CallingApp.StatusLabel.Text = "Invalid domain";
                app.CallingApp.StatusLabel.FontColor = [1 0 0];
            end
            delete(app);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
        
            delete(app);
        end

        % Value changed function: DomainEditField
        function DomainEditFieldValueChanged(app, event)
            value = app.DomainEditField.Value;
            
        end

        % Callback function
        function ApplyallCheckBoxValueChanged(app, event)
            value = app.ApplyallCheckBox.Value;
            app.CallingApp.select_all_x = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DomainEditFieldLabel = uilabel(app.UIFigure);
            app.DomainEditFieldLabel.Text = 'Domain';
            app.DomainEditField = uieditfield(app.UIFigure, 'numeric');
            app.DomainEditField.ValueChangedFcn = createCallbackFcn(app, @DomainEditFieldValueChanged, true);
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply!';
            app.XAxisSetupLabel = uilabel(app.UIFigure);
            app.XAxisSetupLabel.Text = 'X Axis Set-up';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = axis_dialogue_box_anti(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end