classdef dialogue_box < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ApplyButton                     matlab.ui.control.Button
        ONEofthefollowingEcodesmustexistEditField  matlab.ui.control.EditField
        ONEofthefollowingEcodesmustexistEditFieldLabel  matlab.ui.control.Label
        MustNotEcodeEditField           matlab.ui.control.EditField
        MustNotEcodeEditFieldLabel      matlab.ui.control.Label
        MustEcodeEditField_2Label       matlab.ui.control.Label
        MustEcodeEditField_2            matlab.ui.control.EditField
        MarkEcodeEditField_2            matlab.ui.control.EditField
        MarkEcodeEditField_2Label       matlab.ui.control.Label
        RefEcodeEditField_2             matlab.ui.control.EditField
        RefEcodeEditField_2Label        matlab.ui.control.Label
        EnterappropriatetitleTextArea   matlab.ui.control.TextArea
        EnterappropriatetitleTextAreaLabel  matlab.ui.control.Label
        TypeappropriateEcodeeg124Label  matlab.ui.control.Label
        PropertysetupLabel              matlab.ui.control.Label
    end


    properties (Access = public)
    Raster_Sourcenum;
    plot_num;
    end
    
    properties (Access = private)
        CallingApp; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Raster_Sourcenum, plot_num)
            app.CallingApp = mainapp;
            app.plot_num = plot_num;
            app.Raster_Sourcenum = Raster_Sourcenum;
            app.UIFigure.Position = [app.CallingApp.UIFigure.Position(1) app.CallingApp.UIFigure.Position(2) 261 353];
           if ~isempty(app.CallingApp.past_dialogue_ind)
                app.CallingApp.Rasters{app.CallingApp.past_dialogue_ind(1)}.UserData.button{app.CallingApp.past_dialogue_ind(2)}.Enable = 'on';
           end
        
            app.CallingApp.past_dialogue_ind = [app.Raster_Sourcenum app.plot_num];


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.Rasters{app.Raster_Sourcenum}.UserData.button{app.CallingApp.plot_num}.Enable = 'on';
            delete(app)
            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed2(app, event)
           updateplot(app.CallingApp,...
               app.EnterappropriatetitleTextArea.Value,app.RefEcodeEditField_2.Value,...
               app.MarkEcodeEditField_2.Value,app.MustEcodeEditField_2.Value,...
               app.MustNotEcodeEditField.Value,app.ONEofthefollowingEcodesmustexistEditField.Value,...
               app.plot_num);
           app.CallingApp.Rasters{app.Raster_Sourcenum}.UserData.button{app.CallingApp.plot_num}.Enable = 'on';
           delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PropertysetupLabel = uilabel(app.UIFigure);
            app.PropertysetupLabel.Text = 'Property set-up';
            app.TypeappropriateEcodeeg124Label = uilabel(app.UIFigure);
            app.TypeappropriateEcodeeg124Label.Text = 'Type appropriate Ecode (e.g. 1; 2~4)';
            app.EnterappropriatetitleTextAreaLabel = uilabel(app.UIFigure);
            app.EnterappropriatetitleTextAreaLabel.Text = 'Enter appropriate title';
            app.EnterappropriatetitleTextArea = uitextarea(app.UIFigure);
            app.RefEcodeEditField_2Label = uilabel(app.UIFigure);
            app.RefEcodeEditField_2Label.Text = 'Ref. Ecode';
            app.RefEcodeEditField_2 = uieditfield(app.UIFigure, 'text');
            app.MarkEcodeEditField_2Label = uilabel(app.UIFigure);
            app.MarkEcodeEditField_2Label.Text = 'Mark Ecode';
            app.MarkEcodeEditField_2 = uieditfield(app.UIFigure, 'text');
            app.MustEcodeEditField_2 = uieditfield(app.UIFigure, 'text');
            app.MustEcodeEditField_2Label = uilabel(app.UIFigure);
            app.MustEcodeEditField_2Label.Text = 'Must Ecode';
            app.MustNotEcodeEditFieldLabel = uilabel(app.UIFigure);
            app.MustNotEcodeEditFieldLabel.Text = 'Must Not Ecode';
            app.MustNotEcodeEditField = uieditfield(app.UIFigure, 'text');
            app.ONEofthefollowingEcodesmustexistEditFieldLabel = uilabel(app.UIFigure);
            app.ONEofthefollowingEcodesmustexistEditFieldLabel.Text = 'ONE of the following Ecodes must exist';
            app.ONEofthefollowingEcodesmustexistEditField = uieditfield(app.UIFigure, 'text');
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed2, true);
            app.ApplyButton.Text = 'Apply!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dialogue_box(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end