classdef dialogue_box_y < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ApplyallCheckBox           matlab.ui.control.CheckBox
        ApplyButton                matlab.ui.control.Button
        YUpperLimitEditField       matlab.ui.control.NumericEditField
        YUpperLimitEditFieldLabel  matlab.ui.control.Label
        YLowerLimitEditField       matlab.ui.control.NumericEditField
        YLowerLimitEditFieldLabel  matlab.ui.control.Label
        YAxisSetupLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp; % Description
    end
    
    properties (Access = public)
                Raster_Sourcenum; 
                plot_num;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Raster_Sourcenum, plot_num)
            app.CallingApp = mainapp;
            app.UIFigure.Position = [app.CallingApp.UIFigure.Position(1) app.CallingApp.UIFigure.Position(2) 232 171];
            app.CallingApp = mainapp;
            app.Raster_Sourcenum = Raster_Sourcenum;
            app.plot_num = plot_num;

            if ~isempty(app.CallingApp.y_past_dialogue_ind)
                app.CallingApp.Rasters{app.CallingApp.y_past_dialogue_ind(1)}.UserData.yleftlim{app.CallingApp.y_past_dialogue_ind(2)}.Enable = 'on';
                app.CallingApp.Rasters{app.CallingApp.y_past_dialogue_ind(1)}.UserData.xlim{app.CallingApp.y_past_dialogue_ind(2)}.Enable = 'on';

            end

            app.CallingApp.y_past_dialogue_ind = [app.Raster_Sourcenum app.plot_num];


            app.ApplyallCheckBox.Value = 0;

        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            

            updateplot_axis_y(app.CallingApp,app.YLowerLimitEditField.Value,app.YUpperLimitEditField.Value,app.plot_num,app.ApplyallCheckBox.Value)
            app.CallingApp.Rasters{app.Raster_Sourcenum}.UserData.yleftlim{app.plot_num}.Enable = 'on';
            app.CallingApp.Rasters{app.Raster_Sourcenum}.UserData.xlim{app.plot_num}.Enable = 'on';

            app.CallingApp.Rasters{app.Raster_Sourcenum}.UserData.Lock.ImageSource = "Unlock.png";
            app.CallingApp.Rasters{app.Raster_Sourcenum}.UserData.Lock.UserData = 0;
            
            
            offset = length(app.CallingApp.Rasters{app.Raster_Sourcenum}.Children)-app.CallingApp.Raster_index.reference;
            RI = cell2struct(num2cell([cellfun(@(x) x+offset, struct2cell(app.CallingApp.Raster_index))]),...
                {'event_label','ch_num','plot_button','row','col','lock','play','file_open_config',...
                'file_save_config','file_save_data','file_open_data','kernel','neural','behavioral','stop','plotdim','data','config','plotopt','ch_label','DAQ','refresh_text','refresh','reference'}); %label for Children; offset accounted


            app.CallingApp.Rasters{app.Raster_Sourcenum}.Children(RI.lock).UserData = 0;
            app.CallingApp.Rasters{app.Raster_Sourcenum}.UserData.Lock.UserData = 0;
            app.CallingApp.Rasters{app.Raster_Sourcenum}.Children(RI.lock).ImageSource  = "Unlock.png";
            delete(app);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
             app.CallingApp.Rasters{app.Raster_Sourcenum}.UserData.xlim{ app.plot_num}.Enable = 'on';
             app.CallingApp.Rasters{app.Raster_Sourcenum}.UserData.yleftlim{ app.plot_num}.Enable = 'on';
            delete(app);
        end

        % Value changed function: YUpperLimitEditField
        function YUpperLimitEditFieldValueChanged(app, event)
            value = app.YUpperLimitEditField.Value;
            
        end

        % Value changed function: YLowerLimitEditField
        function YLowerLimitEditFieldValueChanged(app, event)
            value = app.YLowerLimitEditField.Value;
            
        end

        % Value changed function: ApplyallCheckBox
        function ApplyallCheckBoxValueChanged(app, event)
            value = app.ApplyallCheckBox.Value;
            app.CallingApp.select_all_y = value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.YAxisSetupLabel = uilabel(app.UIFigure);
            app.YAxisSetupLabel.Text = 'Y Axis Set-up';
            app.YLowerLimitEditFieldLabel = uilabel(app.UIFigure);
            app.YLowerLimitEditFieldLabel.Text = 'Y Lower Limit';
            app.YLowerLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.YLowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @YLowerLimitEditFieldValueChanged, true);
            app.YUpperLimitEditFieldLabel = uilabel(app.UIFigure);
            app.YUpperLimitEditFieldLabel.Text = 'Y Upper Limit';
            app.YUpperLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.YUpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @YUpperLimitEditFieldValueChanged, true);
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply!';
            app.ApplyallCheckBox = uicheckbox(app.UIFigure);
            app.ApplyallCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApplyallCheckBoxValueChanged, true);
            app.ApplyallCheckBox.Text = 'Apply all';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dialogue_box_y(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end