classdef dialogue_box_y_anti < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ApplyButton                matlab.ui.control.Button
        YUpperLimitEditField       matlab.ui.control.NumericEditField
        YUpperLimitEditFieldLabel  matlab.ui.control.Label
        YLowerLimitEditField       matlab.ui.control.NumericEditField
        YLowerLimitEditFieldLabel  matlab.ui.control.Label
        YAxisSetupLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp; % Description
    end
    
    properties (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            app.YLowerLimitEditField.Value = app.CallingApp.UIAxes_2.YLim(1);
            app.YUpperLimitEditField.Value = app.CallingApp.UIAxes_2.YLim(2);
            app.UIFigure.Position = [app.CallingApp.UIFigure.Position(1) app.CallingApp.UIFigure.Position(2) 232 171];

        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if app.YLowerLimitEditField.Value < app.YUpperLimitEditField.Value

            app.CallingApp.UIAxes_2.YLim = [app.YLowerLimitEditField.Value app.YUpperLimitEditField.Value];
            
            else
                app.CallingApp.StatusLabel.Text = "Invalid range";
                app.CallingApp.StatusLabel.FontColor = [1 0 0];
            end

            delete(app);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
          %   app.CallingApp.Rasters{app.Raster_Sourcenum}.UserData.xlim{app.CallingApp.plot_num_axis}.Enable = 'on';
          %   app.CallingApp.Rasters{app.Raster_Sourcenum}.UserData.yleftlim{app.CallingApp.plot_num_axis}.Enable = 'on';
            delete(app);
        end

        % Value changed function: YUpperLimitEditField
        function YUpperLimitEditFieldValueChanged(app, event)
            value = app.YUpperLimitEditField.Value;
            
        end

        % Value changed function: YLowerLimitEditField
        function YLowerLimitEditFieldValueChanged(app, event)
            value = app.YLowerLimitEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.YAxisSetupLabel = uilabel(app.UIFigure);
            app.YAxisSetupLabel.Text = 'Y Axis Set-up';
            app.YLowerLimitEditFieldLabel = uilabel(app.UIFigure);
            app.YLowerLimitEditFieldLabel.Text = 'Y Lower Limit';
            app.YLowerLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.YLowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @YLowerLimitEditFieldValueChanged, true);
            app.YUpperLimitEditFieldLabel = uilabel(app.UIFigure);
            app.YUpperLimitEditFieldLabel.Text = 'Y Upper Limit';
            app.YUpperLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.YUpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @YUpperLimitEditFieldValueChanged, true);
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dialogue_box_y_anti(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end