classdef save_option < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        AppendButton      matlab.ui.control.Button
        OverwriteButton   matlab.ui.control.Button
        SaveOptionsLabel  matlab.ui.control.Label
    end


    properties (Access = public)
    CallingApp
    end

    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp = mainApp;
            app.UIFigure.Position = [app.CallingApp.UIFigure.Position(1) app.CallingApp.UIFigure.Position(2) 407 98];

        end

        % Button pushed function: OverwriteButton
        function OverwriteButtonPushed(app, event)
            app.CallingApp.Rasters{app.CallingApp.Raster_Source}.UserData.inst_save = 1;
            app.CallingApp.Rasters{app.CallingApp.Raster_Source}.UserData.overwrite = 1;
            delete(app);
        end

        % Button pushed function: AppendButton
        function AppendButtonPushed(app, event)
            app.CallingApp.Rasters{app.CallingApp.Raster_Source}.UserData.inst_save = 1;
            app.CallingApp.Rasters{app.CallingApp.Raster_Source}.UserData.append = 1;
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
      %      delete(app);
            offset = length(app.CallingApp.Rasters{app.CallingApp.Raster_Source}.Children)-app.CallingApp.Raster_index.reference;
            RI = cell2struct(num2cell([cellfun(@(x) x+offset, struct2cell(app.CallingApp.Raster_index))]),...
                {'event_label','ch_num','plot_button','row','col','lock','play','file_open_config',...
                'file_save_config','file_save_data','file_open_data','kernel','neural','behavioral','stop','plotdim','data','config','plotopt','ch_label','DAQ','refresh_text','refresh','reference'}); %label for Children; offset accounted


            app.CallingApp.Rasters{app.CallingApp.Raster_Source}.Children(RI.file_save_data).ImageSource = "file_save_icon.PNG";
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SaveOptionsLabel = uilabel(app.UIFigure);
            app.SaveOptionsLabel.Text = 'Save Options';
            app.OverwriteButton = uibutton(app.UIFigure, 'push');
            app.OverwriteButton.ButtonPushedFcn = createCallbackFcn(app, @OverwriteButtonPushed, true);
            app.OverwriteButton.Text = 'Overwrite';
            app.AppendButton = uibutton(app.UIFigure, 'push');
            app.AppendButton.ButtonPushedFcn = createCallbackFcn(app, @AppendButtonPushed, true);
            app.AppendButton.Text = 'Append';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = save_option(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end